classdef Informe_biomecanico_sts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ContinuarButton            matlab.ui.control.Button
        TiempoTotalEditField       matlab.ui.control.NumericEditField
        TiempoTotalEditFieldLabel  matlab.ui.control.Label
        PorcentajeaciertocuartaparteEditField_3  matlab.ui.control.NumericEditField
        PorcentajeaciertocuartaparteEditField_3Label  matlab.ui.control.Label
        PorcentajeaciertocuartaparteEditField_2  matlab.ui.control.NumericEditField
        PorcentajeaciertocuartaparteEditField_2Label  matlab.ui.control.Label
        PorcentajeaciertocuartaparteEditField  matlab.ui.control.NumericEditField
        PorcentajeaciertocuartaparteEditFieldLabel  matlab.ui.control.Label
        DiferencianguloiniciofinalEditField_3  matlab.ui.control.NumericEditField
        DiferencianguloiniciofinalEditField_3Label  matlab.ui.control.Label
        DiferencianguloiniciofinalEditField_2  matlab.ui.control.NumericEditField
        DiferencianguloiniciofinalEditField_2Label  matlab.ui.control.Label
        DiferencianguloiniciofinalEditField  matlab.ui.control.NumericEditField
        DiferencianguloiniciofinalEditFieldLabel  matlab.ui.control.Label
        PorcentajeaciertototalEditField_3  matlab.ui.control.NumericEditField
        PorcentajeaciertototalEditField_3Label  matlab.ui.control.Label
        PorcentajeaciertoterceraparteEditField_3  matlab.ui.control.NumericEditField
        PorcentajeaciertoterceraparteEditField_3Label  matlab.ui.control.Label
        PorcentajeaciertosegundaparteEditField_3  matlab.ui.control.NumericEditField
        PorcentajeaciertosegundaparteEditField_3Label  matlab.ui.control.Label
        PorcentajeaciertoprimeraparteEditField_3  matlab.ui.control.NumericEditField
        PorcentajeaciertoprimeraparteEditField_3Label  matlab.ui.control.Label
        PorcentajeaciertototalEditField_2  matlab.ui.control.NumericEditField
        PorcentajeaciertototalEditField_2Label  matlab.ui.control.Label
        PorcentajeaciertoterceraparteEditField_2  matlab.ui.control.NumericEditField
        PorcentajeaciertoterceraparteEditField_2Label  matlab.ui.control.Label
        PorcentajeaciertosegundaparteEditField_2  matlab.ui.control.NumericEditField
        PorcentajeaciertosegundaparteEditField_2Label  matlab.ui.control.Label
        PorcentajeaciertoprimeraparteEditField_2  matlab.ui.control.NumericEditField
        PorcentajeaciertoprimeraparteEditField_2Label  matlab.ui.control.Label
        PorcentajeaciertototalEditField  matlab.ui.control.NumericEditField
        PorcentajeaciertototalEditFieldLabel  matlab.ui.control.Label
        PorcentajeaciertoterceraparteEditField  matlab.ui.control.NumericEditField
        PorcentajeaciertoterceraparteEditFieldLabel  matlab.ui.control.Label
        PorcentajeaciertosegundaparteEditField  matlab.ui.control.NumericEditField
        PorcentajeaciertosegundaparteEditFieldLabel  matlab.ui.control.Label
        PorcentajeaciertoprimeraparteEditField  matlab.ui.control.NumericEditField
        PorcentajeaciertoprimeraparteEditFieldLabel  matlab.ui.control.Label
        INFORMEBIOMECNICOSTSLabel  matlab.ui.control.Label
        UIAxes2_3                  matlab.ui.control.UIAxes
        UIAxes2_2                  matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp 

        tiempoT = 0;

        curva_tobillo_amputadomalo10k= 0;

        curva_rodilla_amputadomalo10k= 0;

        curva_cadera_amputadomalo10k= 0;
    end
    
    methods (Access = private)
        

        function porcentaje = porcentajeObtenido(malo,superior,inferior,inicio,final,~)
porcentaje = 0;
for i = inicio:final
if(inferior(i)<=malo(i) && malo(i)<=superior(i))
porcentaje= porcentaje+1;
end
end
porcentaje= porcentaje*100/(final-inicio);
return 
        end      


        function comparacion_angulo_tobillo_sts(app)
            %curva mala del amputado
eje_tobillo_sts_amputadomalo10k =linspace(0,100,length(app.curva_tobillo_amputadomalo10k));

%curva buena del amputado a la que le sumamos por un lado y restamos
%por otro para crear el rango
curva_tobillo_amputadobueno = [105.75 105.56 105.23 104.75 104.10 103.26 102.21 101.03 99.93 99.44 100.28 101.68 103.44 105.30 107.19 108.78 110.21 111.12 111.59 111.74];
curva_tobillo_amputadobuenoSuperior = curva_tobillo_amputadobueno+10;
curva_tobillo_amputadobuenoInferior = curva_tobillo_amputadobueno-10;

ejex_tobillo_amputadobuenoSuperior = linspace(0,100,length(curva_tobillo_amputadobuenoSuperior));
pp_tobillo_amputadobuenoSuperior=makima(ejex_tobillo_amputadobuenoSuperior,curva_tobillo_amputadobuenoSuperior);
curva_tobillo_amputadobuenoSuperior10k = ppval(pp_tobillo_amputadobuenoSuperior,linspace(0,100,10000));
eje_tobillo_sts_amputadobuenoSuperior10k =linspace(0,100,length(curva_tobillo_amputadobuenoSuperior10k));

ejex_tobillo_amputadobuenoInferior = linspace(0,100,length(curva_tobillo_amputadobuenoInferior));
pp_tobillo_amputadobuenoInferior=makima(ejex_tobillo_amputadobuenoInferior,curva_tobillo_amputadobuenoInferior);
curva_tobillo_amputadobuenoInferior10k = ppval(pp_tobillo_amputadobuenoInferior,linspace(0,100,10000));
eje_tobillo_sts_amputadobuenoInferior10k =linspace(0,100,length(curva_tobillo_amputadobuenoInferior10k));


%sacamos maximo y minimo para dividir la sts en 3 fases donde x es la
%posicion del valor en la grafica
%[y,x]=max(curva_tobillo_amputadobuenoSuperior10k)
%[y2,x2]=min(curva_tobillo_amputadobuenoSuperior10k)
x= 25*100;
x2=50*100;
x3=75*100;

%sacamos el porcentaje de acierto en la primera fase comparando la grafica
%mala con el rango establecido

porcentajeAcierto1= porcentajeObtenido(app.curva_tobillo_amputadomalo10k,curva_tobillo_amputadobuenoSuperior10k,curva_tobillo_amputadobuenoInferior10k,1,x,app);
app.PorcentajeaciertoprimeraparteEditField.Value=porcentajeAcierto1;

%sacamos el porcentaje de acierto en la segunda fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto2= porcentajeObtenido(app.curva_tobillo_amputadomalo10k,curva_tobillo_amputadobuenoSuperior10k,curva_tobillo_amputadobuenoInferior10k,x,x2,app);
app.PorcentajeaciertosegundaparteEditField.Value=porcentajeAcierto2;

%sacamos el porcentaje de acierto en la tercera fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto3= porcentajeObtenido(app.curva_tobillo_amputadomalo10k,curva_tobillo_amputadobuenoSuperior10k,curva_tobillo_amputadobuenoInferior10k,x2,x3,app);
app.PorcentajeaciertoterceraparteEditField.Value=porcentajeAcierto3;

%sacamos el porcentaje de acierto en la cuarta fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto4= porcentajeObtenido(app.curva_tobillo_amputadomalo10k,curva_tobillo_amputadobuenoSuperior10k,curva_tobillo_amputadobuenoInferior10k,x3,length(curva_tobillo_amputadobuenoSuperior10k),app);
app.PorcentajeaciertocuartaparteEditField.Value=porcentajeAcierto4;


%sacamos el porcentaje de acierto total haciendo la media de las 3
porcentajeAciertoT = (porcentajeAcierto4+porcentajeAcierto3+porcentajeAcierto2+porcentajeAcierto1)/4;
app.PorcentajeaciertototalEditField.Value=porcentajeAciertoT;

%como la sts deberia ser un bucle comparamos el angulo inicial con el
%final para demostrar si es el caso
diferenciaAngulo = round(app.curva_tobillo_amputadomalo10k(1)-app.curva_tobillo_amputadomalo10k(10000));
app.DiferencianguloiniciofinalEditField.Value=diferenciaAngulo;

       %pintamos las graficas para ver la informacion visualmente
plot(app.UIAxes2,eje_tobillo_sts_amputadomalo10k,app.curva_tobillo_amputadomalo10k,'red',eje_tobillo_sts_amputadobuenoSuperior10k,curva_tobillo_amputadobuenoSuperior10k,'--b',eje_tobillo_sts_amputadobuenoInferior10k,curva_tobillo_amputadobuenoInferior10k,'--b');
        end


function comparacion_angulo_rodilla_sts(app)
    %curva mala del amputado
eje_rodilla_sts_amputadomalo10k =linspace(0,100,length(app.curva_rodilla_amputadomalo10k));

%curva buena del amputado a la que le sumamos por un lado y restamos
%por otro para crear el rango
curva_rodilla_amputadobueno = [94.61 94.53 94.56 94.57 94.68 95.06 96.02 97.89 101.08 105.90 112.47 120.32 129.07 138.21 147.20 155.75 163.11 169.06 173.46 176.22 177.86];
curva_rodilla_amputadobuenoSuperior = curva_rodilla_amputadobueno+20;
curva_rodilla_amputadobuenoInferior = curva_rodilla_amputadobueno-20;

ejex_rodilla_amputadobuenoSuperior = linspace(0,100,length(curva_rodilla_amputadobuenoSuperior));
pp_rodilla_amputadobuenoSuperior=makima(ejex_rodilla_amputadobuenoSuperior,curva_rodilla_amputadobuenoSuperior);
curva_rodilla_amputadobuenoSuperior10k = ppval(pp_rodilla_amputadobuenoSuperior,linspace(0,100,10000));
eje_rodilla_sts_amputadobuenoSuperior10k =linspace(0,100,length(curva_rodilla_amputadobuenoSuperior10k));

ejex_rodilla_amputadobuenoInferior = linspace(0,100,length(curva_rodilla_amputadobuenoInferior));
pp_rodilla_amputadobuenoInferior=makima(ejex_rodilla_amputadobuenoInferior,curva_rodilla_amputadobuenoInferior);
curva_rodilla_amputadobuenoInferior10k = ppval(pp_rodilla_amputadobuenoInferior,linspace(0,100,10000));
eje_rodilla_sts_amputadobuenoInferior10k =linspace(0,100,length(curva_rodilla_amputadobuenoInferior10k));

%sacamos maximo y minimo para dividir la sts en 3 fases donde x es la
%posicion del valor en la grafica
%[y,x]=max(curva_rodilla_amputadobuenoSuperior10k)
%[y2,x2]=min(curva_rodilla_amputadobuenoSuperior10k)
x=25*100;
x2=50*100;
x3=75*100;

%sacamos el porcentaje de acierto en la primera fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto1= porcentajeObtenido(app.curva_rodilla_amputadomalo10k,curva_rodilla_amputadobuenoSuperior10k,curva_rodilla_amputadobuenoInferior10k,1,x,app);
app.PorcentajeaciertoprimeraparteEditField_2.Value=porcentajeAcierto1;

%sacamos el porcentaje de acierto en la segunda fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto2= porcentajeObtenido(app.curva_rodilla_amputadomalo10k,curva_rodilla_amputadobuenoSuperior10k,curva_rodilla_amputadobuenoInferior10k,x,x2,app);;
app.PorcentajeaciertosegundaparteEditField_2.Value=porcentajeAcierto2;

%sacamos el porcentaje de acierto en la tercera fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto3= porcentajeObtenido(app.curva_rodilla_amputadomalo10k,curva_rodilla_amputadobuenoSuperior10k,curva_rodilla_amputadobuenoInferior10k,x2,x3,app);
app.PorcentajeaciertoterceraparteEditField_2.Value=porcentajeAcierto3;

%sacamos el porcentaje de acierto en la cuarta fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto4= porcentajeObtenido(app.curva_rodilla_amputadomalo10k,curva_rodilla_amputadobuenoSuperior10k,curva_rodilla_amputadobuenoInferior10k,x3,length(curva_rodilla_amputadobuenoSuperior10k),app);
app.PorcentajeaciertocuartaparteEditField_2.Value=porcentajeAcierto4;

%sacamos el porcentaje de acierto total haciendo la media de las 3
porcentajeAciertoT = (porcentajeAcierto4+porcentajeAcierto3+porcentajeAcierto2+porcentajeAcierto1)/4;
app.PorcentajeaciertototalEditField_2.Value=porcentajeAciertoT;

%como la sts deberia ser un bucle comparamos el angulo inicial con el
%final para demostrar si es el caso
diferenciaAngulo = round(app.curva_rodilla_amputadomalo10k(1)-app.curva_rodilla_amputadomalo10k(10000));
app.DiferencianguloiniciofinalEditField_2.Value=diferenciaAngulo;

       %pintamos las graficas para ver la informacion visualmente
plot(app.UIAxes2_2,eje_rodilla_sts_amputadomalo10k,app.curva_rodilla_amputadomalo10k,'red',eje_rodilla_sts_amputadobuenoSuperior10k,curva_rodilla_amputadobuenoSuperior10k,'--b',eje_rodilla_sts_amputadobuenoInferior10k,curva_rodilla_amputadobuenoInferior10k,'--b');
end


function comparacion_angulo_cadera_sts(app)
    %curva mala del amputado
eje_cadera_sts_amputadomalo10k =linspace(0,100,length(app.curva_cadera_amputadomalo10k));

%curva buena del amputado a la que le sumamos por un lado y restamos
%por otro para crear el rango
curva_cadera_amputadobueno = [135.25 134.57 133.24 130.87 126.94 121.54 115.70 111.60 110.88 113.73 119.39 126.81 135.35 144.33 153.19 161.49 168.60 174.32 178.68 181.56 183.40];
curva_cadera_amputadobuenoSuperior = curva_cadera_amputadobueno+15;
curva_cadera_amputadobuenoInferior = curva_cadera_amputadobueno-15;

ejex_cadera_amputadobuenoSuperior = linspace(0,100,length(curva_cadera_amputadobuenoSuperior));
pp_cadera_amputadobuenoSuperior=makima(ejex_cadera_amputadobuenoSuperior,curva_cadera_amputadobuenoSuperior);
curva_cadera_amputadobuenoSuperior10k = ppval(pp_cadera_amputadobuenoSuperior,linspace(0,100,10000));
eje_cadera_sts_amputadobuenoSuperior10k =linspace(0,100,length(curva_cadera_amputadobuenoSuperior10k));

ejex_cadera_amputadobuenoInferior = linspace(0,100,length(curva_cadera_amputadobuenoInferior));
pp_cadera_amputadobuenoInferior=makima(ejex_cadera_amputadobuenoInferior,curva_cadera_amputadobuenoInferior);
curva_cadera_amputadobuenoInferior10k = ppval(pp_cadera_amputadobuenoInferior,linspace(0,100,10000));
eje_cadera_sts_amputadobuenoInferior10k =linspace(0,100,length(curva_cadera_amputadobuenoInferior10k));

%sacamos maximo y minimo para dividir la sts en 3 fases donde x es la
%posicion del valor en la grafica
%[y,x]=max(curva_cadera_amputadobuenoSuperior10k)
%[y2,x2]=min(curva_cadera_amputadobuenoSuperior10k)
x=25*100;
x2=50*100;
x3=75*100;

%sacamos el porcentaje de acierto en la primera fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto1= porcentajeObtenido(app.curva_cadera_amputadomalo10k,curva_cadera_amputadobuenoSuperior10k,curva_cadera_amputadobuenoInferior10k,1,x,app);
app.PorcentajeaciertoprimeraparteEditField_3.Value=porcentajeAcierto1;

%sacamos el porcentaje de acierto en la segunda fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto2= porcentajeObtenido(app.curva_cadera_amputadomalo10k,curva_cadera_amputadobuenoSuperior10k,curva_cadera_amputadobuenoInferior10k,x,x2,app);
app.PorcentajeaciertosegundaparteEditField_3.Value=porcentajeAcierto1;

%sacamos el porcentaje de acierto en la tercera fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto3= porcentajeObtenido(app.curva_cadera_amputadomalo10k,curva_cadera_amputadobuenoSuperior10k,curva_cadera_amputadobuenoInferior10k,x2,x3,app);
app.PorcentajeaciertoterceraparteEditField_3.Value=porcentajeAcierto1;

%sacamos el porcentaje de acierto en la cuarta fase comparando la grafica
%mala con el rango establecido
porcentajeAcierto4= porcentajeObtenido(app.curva_cadera_amputadomalo10k,curva_cadera_amputadobuenoSuperior10k,curva_cadera_amputadobuenoInferior10k,x3,length(curva_cadera_amputadobuenoSuperior10k),app);
app.PorcentajeaciertocuartaparteEditField_3.Value=porcentajeAcierto4;

%sacamos el porcentaje de acierto total haciendo la media de las 3
porcentajeAciertoT = (porcentajeAcierto4+porcentajeAcierto3+porcentajeAcierto2+porcentajeAcierto1)/4;
app.PorcentajeaciertototalEditField_3.Value=porcentajeAciertoT;

%como la sts deberia ser un bucle comparamos el angulo inicial con el
%final para demostrar si es el caso
diferenciaAngulo = round(app.curva_cadera_amputadomalo10k(1)-app.curva_cadera_amputadomalo10k(10000));
app.DiferencianguloiniciofinalEditField_3.Value=diferenciaAngulo;

       %pintamos las graficas para ver la informacion visualmente
plot(app.UIAxes2_3,eje_cadera_sts_amputadomalo10k,app.curva_cadera_amputadomalo10k,'red',eje_cadera_sts_amputadobuenoSuperior10k,curva_cadera_amputadobuenoSuperior10k,'--b',eje_cadera_sts_amputadobuenoInferior10k,curva_cadera_amputadobuenoInferior10k,'--b');
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, tiempo, curva_tobillo_sts_filtrada, curva_rodilla_sts_filtrada, curva_cadera_sts_filtrada)
            %app.CallingApp = mainApp;

            app.curva_tobillo_amputadomalo10k = curva_tobillo_sts_filtrada;
            app.curva_rodilla_amputadomalo10k = curva_rodilla_sts_filtrada;
            app.curva_cadera_amputadomalo10k = curva_cadera_sts_filtrada;
            app.tiempoT = tiempo;
            
%             app.infoTobillo;
%             infoTobillo(app);


            app.comparacion_angulo_tobillo_sts;
            app.comparacion_angulo_rodilla_sts;
            app.comparacion_angulo_cadera_sts;
            app.TiempoTotalEditField.Value = app.tiempoT;
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            delete(app)
            Pauta_marcha
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Tobillo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Rodilla')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Cadera')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.INFORMEBIOMECNICOSTSLabel = uilabel(app.UIFigure);
            app.INFORMEBIOMECNICOSTSLabel.Text = 'INFORME BIOMECÁNICO STS';
            app.PorcentajeaciertoprimeraparteEditFieldLabel = uilabel(app.UIFigure);
            app.PorcentajeaciertoprimeraparteEditFieldLabel.Text = 'Porcentaje acierto primera parte:';
            app.PorcentajeaciertoprimeraparteEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertosegundaparteEditFieldLabel = uilabel(app.UIFigure);
            app.PorcentajeaciertosegundaparteEditFieldLabel.Text = 'Porcentaje acierto segunda parte:';
            app.PorcentajeaciertosegundaparteEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertoterceraparteEditFieldLabel = uilabel(app.UIFigure);
            app.PorcentajeaciertoterceraparteEditFieldLabel.Text = 'Porcentaje acierto tercera parte:';
            app.PorcentajeaciertoterceraparteEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertototalEditFieldLabel = uilabel(app.UIFigure);
            app.PorcentajeaciertototalEditFieldLabel.Text = 'Porcentaje acierto total:';
            app.PorcentajeaciertototalEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertoprimeraparteEditField_2Label = uilabel(app.UIFigure);
            app.PorcentajeaciertoprimeraparteEditField_2Label.Text = 'Porcentaje acierto primera parte:';
            app.PorcentajeaciertoprimeraparteEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertosegundaparteEditField_2Label = uilabel(app.UIFigure);
            app.PorcentajeaciertosegundaparteEditField_2Label.Text = 'Porcentaje acierto segunda parte:';
            app.PorcentajeaciertosegundaparteEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertoterceraparteEditField_2Label = uilabel(app.UIFigure);
            app.PorcentajeaciertoterceraparteEditField_2Label.Text = 'Porcentaje acierto tercera parte:';
            app.PorcentajeaciertoterceraparteEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertototalEditField_2Label = uilabel(app.UIFigure);
            app.PorcentajeaciertototalEditField_2Label.Text = 'Porcentaje acierto total:';
            app.PorcentajeaciertototalEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertoprimeraparteEditField_3Label = uilabel(app.UIFigure);
            app.PorcentajeaciertoprimeraparteEditField_3Label.Text = 'Porcentaje acierto primera parte:';
            app.PorcentajeaciertoprimeraparteEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertosegundaparteEditField_3Label = uilabel(app.UIFigure);
            app.PorcentajeaciertosegundaparteEditField_3Label.Text = 'Porcentaje acierto segunda parte:';
            app.PorcentajeaciertosegundaparteEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertoterceraparteEditField_3Label = uilabel(app.UIFigure);
            app.PorcentajeaciertoterceraparteEditField_3Label.Text = 'Porcentaje acierto tercera parte:';
            app.PorcentajeaciertoterceraparteEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertototalEditField_3Label = uilabel(app.UIFigure);
            app.PorcentajeaciertototalEditField_3Label.Text = 'Porcentaje acierto total:';
            app.PorcentajeaciertototalEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.DiferencianguloiniciofinalEditFieldLabel = uilabel(app.UIFigure);
            app.DiferencianguloiniciofinalEditFieldLabel.Text = 'Diferencia ángulo inicio-final:';
            app.DiferencianguloiniciofinalEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiferencianguloiniciofinalEditField_2Label = uilabel(app.UIFigure);
            app.DiferencianguloiniciofinalEditField_2Label.Text = 'Diferencia ángulo inicio-final:';
            app.DiferencianguloiniciofinalEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DiferencianguloiniciofinalEditField_3Label = uilabel(app.UIFigure);
            app.DiferencianguloiniciofinalEditField_3Label.Text = 'Diferencia ángulo inicio-final:';
            app.DiferencianguloiniciofinalEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertocuartaparteEditFieldLabel = uilabel(app.UIFigure);
            app.PorcentajeaciertocuartaparteEditFieldLabel.Text = 'Porcentaje acierto cuarta parte:';
            app.PorcentajeaciertocuartaparteEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertocuartaparteEditField_2Label = uilabel(app.UIFigure);
            app.PorcentajeaciertocuartaparteEditField_2Label.Text = 'Porcentaje acierto cuarta parte:';
            app.PorcentajeaciertocuartaparteEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeaciertocuartaparteEditField_3Label = uilabel(app.UIFigure);
            app.PorcentajeaciertocuartaparteEditField_3Label.Text = 'Porcentaje acierto cuarta parte:';
            app.PorcentajeaciertocuartaparteEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.TiempoTotalEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoTotalEditFieldLabel.Text = 'Tiempo Total:';
            app.TiempoTotalEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Informe_biomecanico_sts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end