classdef Pauta_carrera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        InicialacarrerahastacompletarunazancadacompletaLabel  matlab.ui.control.Label
        Image            matlab.ui.control.Image
        ContinuarButton  matlab.ui.control.Button
        ComenzardesdeunaposicindebipedestacinLabel  matlab.ui.control.Label
        PautaspararealizarlacarreraLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
properties (Access = private)
torso = [10 30 17;40 30 17;40 10 17;10 10 17;10 30 50;40 30 50;40 10 50;10 10 50];
Pecho=[7 30 50; 43 30 50; 7 7 50; 43 7 50; 7 30 66; 43 30 66; 7 7 66; 43 7 66];
brazoder=[43 25 66; 51 25 66; 43 15 66; 51 15 66; 43 25 -5; 51 25 -5; 43 15 -5; 51 15 -5];
brazoizq=[7 25 66; -1 25 66; 7 15 66; -1 15 66; 7 25 -5; -1 25 -5; 7 15 -5; -1 15 -5];
cadera=[10 30 17;40 30 17;10 10 17;40 10 17;7 32 1;43 32 1;7 10 1;43 10, 1];
musloizq=[7 32 1;25 32 1;7 10 1;25 10 1;9 25 -49;23 25 -49;9 10 -49;23 10 -49];
musloder=[25 32 1;43 32 1;25 10 1;42 10 1;27 25 -49;41 25 -49;27 10 -49;41 10, -49];
pantorrillaizq =[9 25 -49;23 25 -49;9 10 1-49;23 10 -49;10 25 -82;22 25 -82;10 10 -82;22 10 -82];
pantorrillader=[27 25 -49;41 25 -49;27 10 -49;41 10 -49;28 25 -82;40 25 -82;28 10 -82;40 10, -82];
pieizq=[10 25 -82;22 25 -82;10 0 -82;22 0 -82;10 25 -91;22 25 -91;10 0 -91;22 0 -91];
pieder=[28 25 -82;40 25 -82;28 0 -82;40 0 -82;28 25 -91;40 25 -91;28 0 -91;40 0 -91];
cuello=[7 30 66; 43 30 66; 7 10 66; 43 10 66;21.5 25 71; 28.5 25 71; 21.5 15 71; 28.5 15 71 ];

%Formamos las caras de cada figura, para ello decimos que puntos se tienen
%que unir en cada cara. 
F=[1,2,6,5;2,3,7,6;3,4,8,7;1,4,8,5;1,2,3,4;5,6,7,8];% este solo funciona con el tronco
F2=[1,2,6,5;2,4,8,6;3,4,8,7;1,3,7,5;1,2,4,3;5,6,8,7];
%Aplicamos color para distinguir las figuras
C= [0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7];
end

methods (Access = private)

function muestras = muestrasSano(~,curva)

ejex_sano= linspace(0,100,length(curva));
pp_sano=makima(ejex_sano,curva);

muestras = ppval(pp_sano,linspace(0,100,10000));
end

function R = rotationMatrix(~,yaw,pitch,roll)
%esta funcion nos permite formar una matriz de rotacion a partir de los
%angulos que le demos
matrixYaw = [cosd(yaw),-sind(yaw),0;...
 sind(yaw),cosd(yaw),0;0,0,1];
matrixPitch = [cosd(pitch),0,sind(pitch);...
 0,1,0;-sind(pitch),0,cosd(pitch)];
matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
 0,sind(roll),cosd(roll)];
R = matrixYaw*matrixPitch*matrixRoll;
end
function modelRotated = applyRotationNx3(app,model,displacement,R)
    %esta funcion nos permite rotar un segmento a partir de una matriz de
    %rotacion.
    
    %applyRotationNx3(modelo que quieres rotar, eje de rotacion, matriz de rotacion)
    modelRotated = R*(model-repmat(displacement,size(model,1),1))';
    modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
end
function cadenaCinematicaCarreraSana(app,YPRmusloder,YPRpantorrillader,YPRpieder,YPRmusloizq,YPRpantorrillaizq,YPRpieizq)
                
    %Con los datos del YPR sacamos matriz de rotacion con respecto a los angulos
    Rmusloder = app.rotationMatrix(YPRmusloder(1),YPRmusloder(2),YPRmusloder(3));
    Rpantorillader = app.rotationMatrix(YPRpantorrillader(1),YPRpantorrillader(2),YPRpantorrillader(3));
    Rpieder = app.rotationMatrix(YPRpieder(1),YPRpieder(2),YPRpieder(3));
    
    %Rotamos cada segmento del cuerpo. Importante asegurarnos de mantener la
    %cadena para conservar la union entre partes. Tambien hay que poner los
    %puntos que serviran como eje de rotacion.
    musloderRotado = app.applyRotationNx3(app.musloder,...
        (app.musloder(1,:)+app.musloder(2,:)+app.musloder(3,:)+app.musloder(4,:))/4,Rmusloder);
    pantorrilladerRotado = app.applyRotationNx3(app.applyRotationNx3(app.pantorrillader,...
        (app.pantorrillader(1,:)+app.pantorrillader(2,:)+app.pantorrillader(3,:)+app.pantorrillader(4,:))/4,Rpantorillader), ...
        (app.musloder(1,:)+app.musloder(2,:)+app.musloder(3,:)+app.musloder(4,:))/4,Rmusloder);
    piederRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(app.pieder,...
        (app.pantorrillader(5,:)+app.pantorrillader(6,:)+app.pantorrillader(7,:)+app.pantorrillader(8,:))/4,Rpieder), ...
        (app.pantorrillader(1,:)+app.pantorrillader(2,:)+app.pantorrillader(3,:)+app.pantorrillader(4,:))/4,Rpantorillader), ...
        (app.musloder(1,:)+app.musloder(2,:)+app.musloder(3,:)+app.musloder(4,:))/4,Rmusloder);
    
    %Conseguimos la matriz de rotacion de la pierna izquierda
    Rmusloizq = app.rotationMatrix(YPRmusloizq(1),YPRmusloizq(2),YPRmusloizq(3));
    Rpantorillaizq = app.rotationMatrix(YPRpantorrillaizq(1),YPRpantorrillaizq(2),YPRpantorrillaizq(3));
    Rpieizq = app.rotationMatrix(YPRpieizq(1),YPRpieizq(2),YPRpieizq(3));
    
    %Rotamos los segmentos de la pierna izquierda
    musloizqRotado = app.applyRotationNx3(app.musloizq,...
        (app.musloizq(1,:)+app.musloizq(2,:)+app.musloizq(3,:)+app.musloizq(4,:))/4,Rmusloizq);
    pantorrillaizqRotado = app.applyRotationNx3(app.applyRotationNx3(app.pantorrillaizq,...
        (app.pantorrillaizq(1,:)+app.pantorrillaizq(2,:)+app.pantorrillaizq(3,:)+app.pantorrillaizq(4,:))/4,Rpantorillaizq), ...
        (app.musloizq(1,:)+app.musloizq(2,:)+app.musloizq(3,:)+app.musloizq(4,:))/4,Rmusloizq);
    pieizqRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(app.pieizq,...
        (app.pantorrillaizq(5,:)+app.pantorrillaizq(6,:)+app.pantorrillaizq(7,:)+app.pantorrillaizq(8,:))/4,Rpieizq), ...
        (app.pantorrillaizq(1,:)+app.pantorrillaizq(2,:)+app.pantorrillaizq(3,:)+app.pantorrillaizq(4,:))/4,Rpantorillaizq), ...
        (app.musloizq(1,:)+app.musloizq(2,:)+app.musloizq(3,:)+app.musloizq(4,:))/4,Rmusloizq);
    
    %Rotamos los brazos con respecto al angulo de la cadera
    Rbrazoder = app.rotationMatrix(YPRmusloder(1),YPRmusloder(2),-YPRmusloder(3)/1.5);
    Rbrazoizq = app.rotationMatrix(YPRmusloizq(1),YPRmusloizq(2),YPRmusloder(3)/1.5);
    
    brazoderRotado = app.applyRotationNx3(app.brazoder,...
        (app.brazoder(1,:)+app.brazoder(2,:)+app.brazoder(3,:)+app.brazoder(4,:))/4,Rbrazoder);
    brazoizqRotado = app.applyRotationNx3(app.brazoizq,...
        (app.brazoizq(1,:)+app.brazoizq(2,:)+app.brazoizq(3,:)+app.brazoizq(4,:))/4,Rbrazoizq);  
    
    cla(app.UIAxes)
    patch(app.UIAxes,'faces',app.F2,'Vertices',app.cuello,'FaceVertexCData',app.C,'FaceColor','flat');
    patch(app.UIAxes,'faces',app.F,'Vertices',app.torso,'FaceVertexCData',app.C,'FaceColor','flat');
    patch(app.UIAxes,'faces',app.F2,'Vertices',app.Pecho,'FaceVertexCData',app.C,'FaceColor','flat');
    patch(app.UIAxes,'faces',app.F2,'Vertices',brazoderRotado,'FaceVertexCData',app.C,'FaceColor','flat');
    patch(app.UIAxes,'faces',app.F2,'Vertices',brazoizqRotado,'FaceVertexCData',app.C,'FaceColor','flat');
    patch(app.UIAxes,'faces',app.F2,'Vertices',app.cadera,'FaceVertexCData',app.C,'FaceColor','flat');
    patch(app.UIAxes,'faces',app.F2,'vertices',musloderRotado,'FaceVertexCData',app.C,'FaceColor','flat')
    patch(app.UIAxes,'faces',app.F2,'vertices',pantorrilladerRotado,'FaceVertexCData',app.C,'FaceColor','flat')
    patch(app.UIAxes,'faces',app.F2,'vertices',piederRotado,'FaceVertexCData',app.C,'FaceColor','flat')
    patch(app.UIAxes,'faces',app.F2,'Vertices',musloizqRotado,'FaceVertexCData',app.C,'FaceColor','flat');
    patch(app.UIAxes,'faces',app.F2,'Vertices',pantorrillaizqRotado,'FaceVertexCData',app.C,'FaceColor','flat');
    patch(app.UIAxes,'faces',app.F2,'Vertices',pieizqRotado,'FaceVertexCData',app.C,'FaceColor','flat');
    hold(app.UIAxes,'on')
    [x, y, z]=sphere(50);
    surf(app.UIAxes,12.5*x+25,12.5*y+20,12.5*z+81);
    
    view(app.UIAxes,3)
    axis(app.UIAxes,[-90 90 -90 90 -100 100])
    app.UIAxes.CameraPosition=[-50 -50 50 ];
    drawnow;
    
end

function pintar_3D_carrera(app)
    
    
    mC = app.muestrasSano([40 36 30 22 15  2 -11  -26 -33 -35 -34 -31 -25 -17 5 27 52 63 68  65 58 50 40]);
    mR = [152 151 150 151 156 161 165 166 158 147 120 100 76 53 48 54 67 85 100 115 130 149 152];
    mR = mR - 180;
    mR = app.muestrasSano(mR);
    mT = app.muestrasSano([3 -1 -9 -15 -19 -20 -12 4 20 22 20 18 17 16 14 13 10 7 4 3 2 3 4]);


    mIC = [mC(5000:10000) mC(1:5000)];
    mIR = [mR(5000:10000) mR(1:5000)];
    mIT = [mT(5000:10000) mT(1:5000)] ;  
    
    for i = 1:100
     cla(app.UIAxes)
       
        app.cadenaCinematicaCarreraSana([0,0,-mC(i*100)],[0,0,-mR(i*100)],[0,0,-mT(i*100)],[0,0,-mIC(i*100)],[0,0,-mIR(i*100)],[0,0,-mIT(i*100)]);
        pause(0.01)
    end
end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ContinuarButton.Enable = "off";

            pintar_3D_carrera(app)

            app.ContinuarButton.Enable = "on";

        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            ejercicio_curva_carrera
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app)
            Informe_biomecanico_marcha
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Representación del movimiento')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.PautaspararealizarlacarreraLabel = uilabel(app.UIFigure);
            app.PautaspararealizarlacarreraLabel.Text = 'Pautas para realizar la carrera';
            app.ComenzardesdeunaposicindebipedestacinLabel = uilabel(app.UIFigure);
            app.ComenzardesdeunaposicindebipedestacinLabel.Text = '- Comenzar desde una posición de bipedestación ';
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Screenshot 2022-11-13 at 14.13.57.png';
            app.InicialacarrerahastacompletarunazancadacompletaLabel = uilabel(app.UIFigure);
            app.InicialacarrerahastacompletarunazancadacompletaLabel.Text = '- Inicia la carrera hasta completar una zancada completa';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pauta_carrera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end