classdef Pauta_marcha < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        zancadacompletaLabel  matlab.ui.control.Label
        IniciaelciclodelamarchahastarealizarunaLabel  matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        ContinuarButton       matlab.ui.control.Button
        ComienzadesdeunaposicindebipedestacinLabel  matlab.ui.control.Label
        PautaspararealizarlamarchaLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function modelRotated = applyRotationNx3(~,model,displacement,R)
        %esta funcion nos permite rotar un segmento a partir de una matriz de
        %rotacion.
        
        %applyRotationNx3(modelo que quieres rotar, eje de rotacion, matriz de rotacion)
        modelRotated = R*(model-repmat(displacement,size(model,1),1))';
        modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
        end
        
        function R = rotationMatrix(~,yaw,pitch,roll)
            %esta funcion nos permite formar una matriz de rotacion a partir de los
            %angulos que le demos
            matrixYaw = [cosd(yaw),-sind(yaw),0;...
                sind(yaw),cosd(yaw),0;0,0,1];
            matrixPitch = [cosd(pitch),0,sind(pitch);...
                0,1,0;-sind(pitch),0,cosd(pitch)];
            matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
                0,sind(roll),cosd(roll)];
            R = matrixYaw*matrixPitch*matrixRoll;
        end
        
        function muestras = muestras(~,curva)

        ejex_sano= linspace(0,100,length(curva));
        pp_sano=makima(ejex_sano,curva);
        
        muestras = ppval(pp_sano,linspace(0,100,10000));
        end
        
        function cadenaCinematicaMarchaSana(app,YPRmusloder,YPRpantorrillader,YPRpieder,YPRmusloizq,YPRpantorrillaizq,YPRpieizq)
% Plantilla de como tienen que ponerse los datos para que se vea bien el
% movimiento:
% YPRmuslo = [0,0,flexion/extension] (- flexion/ + extension)
% YPRrodilla = [0,0,flexion] (solo + porque la rodilla no hace extension)
% YPRtobillo = [0,0,flexion/extension] (- flexion dorsal/ + flexion plantar)

fTorso = [1,2,6,5;2,3,7,6;3,4,8,7;1,4,8,5;1,2,3,4;5,6,7,8];
f = [1,2,6,5;2,4,8,6;3,4,8,7;1,3,7,5;1,2,4,3;5,6,8,7];
color = [0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7];

%Le damos los puntos de cada solido
cuello=[7 30 66; 43 30 66; 7 10 66; 43 10 66;21.5 25 71; 28.5 25 71; 21.5 15 71; 28.5 15 71 ];
torso=[10 30 17; 40 30 17; 40 10 17; 10 10 17; 10 30 50; 40 30 50; 40 10 50; 10 10 50];
Pecho=[7 30 50; 43 30 50; 7 7 50; 43 7 50; 7 30 66; 43 30 66; 7 7 66; 43 7 66];
brazoizq=[43 25 66; 51 25 66; 43 15 66; 51 15 66; 43 25 -5; 51 25 -5; 43 15 -5; 51 15 -5];
brazoder=[7 25 66; -1 25 66; 7 15 66; -1 15 66; 7 25 -5; -1 25 -5; 7 15 -5; -1 15 -5];
cadera=[10 30 17;40 30 17;10 10 17;40 10 17;7 32 1;43 32 1;7 10 1;43 10, 1];
musloder=[7 32 1;25 32 1;7 10 1;25 10 1;9 25 -49;23 25 -49;9 10 -49;23 10 -49];
pantorrillader =[9 25 -49;23 25 -49;9 10 1-49;23 10 -49;10 25 -82;22 25 -82;10 10 -82;22 10 -82];
pieder=[10 25 -82;22 25 -82;10 0 -82;22 0 -82;10 25 -91;22 25 -91;10 0 -91;22 0 -91];
musloizq=[25 32 1;43 32 1;25 10 1;42 10 1;27 25 -49;41 25 -49;27 10 -49;41 10, -49];
pantorrillaizq=[27 25 -49;41 25 -49;27 10 -49;41 10 -49;28 25 -82;40 25 -82;28 10 -82;40 10, -82];
pieizq=[28 25 -82;40 25 -82;28 0 -82;40 0 -82;28 25 -91;40 25 -91;28 0 -91;40 0 -91];

%Con los datos del YPR sacamos matriz de rotacion con respecto a los angulos
Rmusloder = app.rotationMatrix(YPRmusloder(1),YPRmusloder(2),YPRmusloder(3));
Rpantorillader = app.rotationMatrix(YPRpantorrillader(1),YPRpantorrillader(2),YPRpantorrillader(3));
Rpieder = app.rotationMatrix(YPRpieder(1),YPRpieder(2),YPRpieder(3));

%Rotamos cada segmento del cuerpo. Importante asegurarnos de mantener la
%cadena para conservar la union entre partes. Tambien hay que poner los
%puntos que serviran como eje de rotacion.
%Plantilla:
% musculoRotado = applyRotationNx3(musculoARotar, ejeDeRotacion, matrizDeRotacion)
musloderRotado = app.applyRotationNx3(musloder,...
    (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmusloder);
pantorrilladerRotado = app.applyRotationNx3(app.applyRotationNx3(pantorrillader,...
    (pantorrillader(1,:)+pantorrillader(2,:)+pantorrillader(3,:)+pantorrillader(4,:))/4,Rpantorillader), ...
    (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmusloder);
piederRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(pieder,...
    (pantorrillader(5,:)+pantorrillader(6,:)+pantorrillader(7,:)+pantorrillader(8,:))/4,Rpieder), ...
    (pantorrillader(1,:)+pantorrillader(2,:)+pantorrillader(3,:)+pantorrillader(4,:))/4,Rpantorillader), ...
    (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmusloder);

%Conseguimos la matriz de rotacion de la pierna izquierda
Rmusloizq = app.rotationMatrix(YPRmusloizq(1),YPRmusloizq(2),YPRmusloizq(3));
Rpantorillaizq = app.rotationMatrix(YPRpantorrillaizq(1),YPRpantorrillaizq(2),YPRpantorrillaizq(3));
Rpieizq = app.rotationMatrix(YPRpieizq(1),YPRpieizq(2),YPRpieizq(3));

%Rotamos los segmentos de la pierna izquierda
musloizqRotado = app.applyRotationNx3(musloizq,...
    (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmusloizq);
pantorrillaizqRotado = app.applyRotationNx3(app.applyRotationNx3(pantorrillaizq,...
    (pantorrillaizq(1,:)+pantorrillaizq(2,:)+pantorrillaizq(3,:)+pantorrillaizq(4,:))/4,Rpantorillaizq), ...
    (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmusloizq);
pieizqRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(pieizq,...
    (pantorrillaizq(5,:)+pantorrillaizq(6,:)+pantorrillaizq(7,:)+pantorrillaizq(8,:))/4,Rpieizq), ...
    (pantorrillaizq(1,:)+pantorrillaizq(2,:)+pantorrillaizq(3,:)+pantorrillaizq(4,:))/4,Rpantorillaizq), ...
    (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmusloizq);

%Rotamos los brazos con respecto al angulo de la cadera
Rbrazoder = app.rotationMatrix(YPRmusloder(1),YPRmusloder(2),-YPRmusloder(3)/1.5);
Rbrazoizq = app.rotationMatrix(YPRmusloizq(1),YPRmusloizq(2),YPRmusloder(3)/1.5);

brazoderRotado = app.applyRotationNx3(brazoder,...
    (brazoder(1,:)+brazoder(2,:)+brazoder(3,:)+brazoder(4,:))/4,Rbrazoder);
brazoizqRotado = app.applyRotationNx3(brazoizq,...
    (brazoizq(1,:)+brazoizq(2,:)+brazoizq(3,:)+brazoizq(4,:))/4,Rbrazoizq);

%Montamos la figura y la pintamos

patch(app.UIAxes,'faces',f,'Vertices',cuello,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',fTorso,'Vertices',torso,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',Pecho,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',brazoderRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',brazoizqRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',cadera,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',musloderRotado,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',pantorrilladerRotado,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',piederRotado,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'Vertices',musloizqRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',pantorrillaizqRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',pieizqRotado,'FaceVertexCData',color,'FaceColor','flat');
hold(app.UIAxes,'on')
[x, y, z]=sphere(50);
surf(app.UIAxes,12.5*x+25,12.5*y+20,12.5*z+81);

view(app.UIAxes,3)
axis(app.UIAxes,[-90 90 -90 90 -100 100])
app.UIAxes.CameraPosition=[-50 -50 50 ];
drawnow;
        end
        
   function pintar_marcha_sana_3D(app)
    muestrasCadera = app.muestras([27.5 27 24.5 20 14 9.5 3.5 -1.5 -7 -10 -12 -11 -5.5 1 7.5 12 17 22.5 28 29.5 27.5 26 26.5]);
    muestrasRodilla = app.muestras([0 8 15 17 13 10 6 4 3 5 10 20 30 37 48 55 64 54 43 30 18 8 -1 2])
    muestrasTobillo = app.muestras([-5 -12 -18 -12 -5 0 4 8 10 15 10 0 -10 -25 -33 -35 -30 -23 -14 -5 -6 -5 -8])

        % Las muestras de la pierna izquierda son las mismas que la de la derecha
        % pero las desplazamos la mitad para que este en otro momento del
        % movimiento y tenga sentido
        muestrasCaderaIzq = [muestrasCadera(5000:10000) muestrasCadera(1:5000)]
        muestrasRodillaIzq = [muestrasRodilla(5000:10000) muestrasRodilla(1:5000)]
        muestrasTobilloIzq = [muestrasTobillo(5000:10000) muestrasTobillo(1:5000)]   
        
        % Este for dibuja de forma continuada el movimiento del muñeco asegurandose
        % de borrarlo cada vez antes de pintar de nuevo
        for i = 1:100
           cla(app.UIAxes)
                app.cadenaCinematicaMarchaSana([0,0,-muestrasCadera(i*100)],[0,0,muestrasRodilla(i*100)],[0,0,-muestrasTobillo(i*100)],[0,0,-muestrasCaderaIzq(i*100)],[0,0,muestrasRodillaIzq(i*100)],[0,0,-muestrasTobilloIzq(i*100)]);
            pause(0.02)
        end
    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ContinuarButton.Enable = "off";

            pintar_marcha_sana_3D(app)

            app.ContinuarButton.Enable = "on";


        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
          ejercicio_curva_marcha
            delete(app)
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
delete(app)
Informe_biomecanico_sts
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Representación del movimiento')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PautaspararealizarlamarchaLabel = uilabel(app.UIFigure);
            app.PautaspararealizarlamarchaLabel.Text = 'Pautas para realizar la marcha';
            app.ComienzadesdeunaposicindebipedestacinLabel = uilabel(app.UIFigure);
            app.ComienzadesdeunaposicindebipedestacinLabel.Text = '- Comienza desde una posición de bipedestación ';
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Screenshot 2022-11-13 at 14.13.57.png';
            app.IniciaelciclodelamarchahastarealizarunaLabel = uilabel(app.UIFigure);
            app.IniciaelciclodelamarchahastarealizarunaLabel.Text = '- Inicia el ciclo de la marcha hasta realizar una';
            app.zancadacompletaLabel = uilabel(app.UIFigure);
            app.zancadacompletaLabel.Text = ' zancada completa';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pauta_marcha

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end