classdef Pautas_ejercicio_sts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        paranoinfluirenelmovimientoLabel  matlab.ui.control.Label
        LasmanossedebencolocarseencimadelosmuslosLabel  matlab.ui.control.Label
        AmbospiesdebenmirarhaciaelfrenteLabel  matlab.ui.control.Label
        Lasrodillasdebenestarflexionadas90Label  matlab.ui.control.Label
        Image            matlab.ui.control.Image
        ContinuarButton  matlab.ui.control.Button
        ComienzadesdeunaposicinsentadaLabel  matlab.ui.control.Label
        PautaspararealizarelejercicioSTSLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function modelRotated = applyRotationNx3(~,model,displacement,R)
        %esta funcion nos permite rotar un segmento a partir de una matriz de
        %rotacion.
        
        %applyRotationNx3(modelo que quieres rotar, eje de rotacion, matriz de rotacion)
        modelRotated = R*(model-repmat(displacement,size(model,1),1))';
        modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
        end
        
        function R = rotationMatrix(~,yaw,pitch,roll)
            %esta funcion nos permite formar una matriz de rotacion a partir de los
            %angulos que le demos
            matrixYaw = [cosd(yaw),-sind(yaw),0;...
                sind(yaw),cosd(yaw),0;0,0,1];
            matrixPitch = [cosd(pitch),0,sind(pitch);...
                0,1,0;-sind(pitch),0,cosd(pitch)];
            matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
                0,sind(roll),cosd(roll)];
            R = matrixYaw*matrixPitch*matrixRoll;
        end
        
        
        function muestras = muestras(~,curva)

        ejex_sano= linspace(0,100,length(curva));
        pp_sano=makima(ejex_sano,curva);
        
        muestras = ppval(pp_sano,linspace(0,100,10000));
        end
      function cadenaCinematicaSTSSana(app,YPRtronco,YPRmuslo,YPRpantorrilla,YPRpie)
% Plantilla de como tienen que ponerse los datos para que se vea bien el
% movimiento:
% YPRtronco = [0,0, flexion/extension] (- flexion/ + extension)
% YPRmuslo = [0,0,flexion/extension] (+ flexion/ - extension)
% YPRrodilla = [0,0,flexion] (solo - porque la rodilla no hace extension)
% YPRtobillo = [0,0,flexion/extension] (- flexion dorsal/ + flexion plantar)

fTorso = [1,2,6,5;2,3,7,6;3,4,8,7;1,4,8,5;1,2,3,4;5,6,7,8];
f = [1,2,6,5;2,4,8,6;3,4,8,7;1,3,7,5;1,2,4,3;5,6,8,7];
color = [0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7];

%Le damos los puntos de cada solido
cuello=[7 30 66; 43 30 66; 7 10 66; 43 10 66;21.5 25 71; 28.5 25 71; 21.5 15 71; 28.5 15 71 ];
torso=[10 30 17; 40 30 17; 40 10 17; 10 10 17; 10 30 50; 40 30 50; 40 10 50; 10 10 50];
Pecho=[7 30 50; 43 30 50; 7 7 50; 43 7 50; 7 30 66; 43 30 66; 7 7 66; 43 7 66];
brazoizq=[43 25 66; 51 25 66; 43 15 66; 51 15 66; 43 25 -5; 51 25 -5; 43 15 -5; 51 15 -5];
brazoder=[7 25 66; -1 25 66; 7 15 66; -1 15 66; 7 25 -5; -1 25 -5; 7 15 -5; -1 15 -5];
cadera=[10 30 17;40 30 17;10 10 17;40 10 17;7 32 1;43 32 1;7 10 1;43 10, 1];
musloder=[7 32 1;25 32 1;7 10 1;25 10 1;9 25 -49;23 25 -49;9 10 -49;23 10 -49];
pantorrillader =[9 25 -49;23 25 -49;9 10 1-49;23 10 -49;10 25 -82;22 25 -82;10 10 -82;22 10 -82];
pieder=[10 25 -82;22 25 -82;10 0 -82;22 0 -82;10 25 -91;22 25 -91;10 0 -91;22 0 -91];
musloizq=[25 32 1;43 32 1;25 10 1;42 10 1;27 25 -49;41 25 -49;27 10 -49;41 10, -49];
pantorrillaizq=[27 25 -49;41 25 -49;27 10 -49;41 10 -49;28 25 -82;40 25 -82;28 10 -82;40 10, -82];
pieizq=[28 25 -82;40 25 -82;28 0 -82;40 0 -82;28 25 -91;40 25 -91;28 0 -91;40 0 -91];

%Con los datos del YPR sacamos matriz de rotacion con respecto a los angulos
Rtronco = app.rotationMatrix(YPRtronco(1),YPRtronco(2),YPRtronco(3));
Rmuslo = app.rotationMatrix(YPRmuslo(1),YPRmuslo(2),YPRmuslo(3));
Rpantorilla = app.rotationMatrix(YPRpantorrilla(1),YPRpantorrilla(2),YPRpantorrilla(3));
Rpie = app.rotationMatrix(YPRpie(1),YPRpie(2),YPRpie(3));

%Rotamos cada segmento del cuerpo. Importante asegurarnos de mantener la
%cadena para conservar la union entre partes. Tambien hay que poner los
%puntos que serviran como eje de rotacion.
%Plantilla:
% musculoRotado = applyRotationNx3(musculoARotar, ejeDeRotacion, matrizDeRotacion)
caderaRotado = app.applyRotationNx3(cadera,...
    (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
torsoRotado = app.applyRotationNx3(torso,...
    (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
pechoRotado = app.applyRotationNx3(Pecho,...
    (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
brazoderRotado = app.applyRotationNx3(brazoder,...
    (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
brazoizqRotado = app.applyRotationNx3(brazoizq,...
    (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
cuelloRotado = app.applyRotationNx3(cuello,...
    (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);

musloderRotado = app.applyRotationNx3(musloder,...
    (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmuslo);
pantorrilladerRotado = app.applyRotationNx3(app.applyRotationNx3(pantorrillader,...
    (pantorrillader(1,:)+pantorrillader(2,:)+pantorrillader(3,:)+pantorrillader(4,:))/4,Rpantorilla), ...
    (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmuslo);
piederRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(pieder,...
    (pantorrillader(5,:)+pantorrillader(6,:)+pantorrillader(7,:)+pantorrillader(8,:))/4,Rpie), ...
    (pantorrillader(1,:)+pantorrillader(2,:)+pantorrillader(3,:)+pantorrillader(4,:))/4,Rpantorilla), ...
    (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmuslo);

musloizqRotado = app.applyRotationNx3(musloizq,...
    (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmuslo);
pantorrillaizqRotado = app.applyRotationNx3(app.applyRotationNx3(pantorrillaizq,...
    (pantorrillaizq(1,:)+pantorrillaizq(2,:)+pantorrillaizq(3,:)+pantorrillaizq(4,:))/4,Rpantorilla), ...
    (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmuslo);
pieizqRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(pieizq,...
    (pantorrillaizq(5,:)+pantorrillaizq(6,:)+pantorrillaizq(7,:)+pantorrillaizq(8,:))/4,Rpie), ...
    (pantorrillaizq(1,:)+pantorrillaizq(2,:)+pantorrillaizq(3,:)+pantorrillaizq(4,:))/4,Rpantorilla), ...
    (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmuslo);

%Montamos la figura y la pintamos

patch(app.UIAxes,'faces',f,'Vertices',cuelloRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',fTorso,'Vertices',torsoRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',pechoRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',brazoderRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',brazoizqRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',caderaRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'vertices',musloderRotado,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',pantorrilladerRotado,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'vertices',piederRotado,'FaceVertexCData',color,'FaceColor','flat')
patch(app.UIAxes,'faces',f,'Vertices',musloizqRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',pantorrillaizqRotado,'FaceVertexCData',color,'FaceColor','flat');
patch(app.UIAxes,'faces',f,'Vertices',pieizqRotado,'FaceVertexCData',color,'FaceColor','flat');
hold(app.UIAxes,'on')

xyz = sum(cuelloRotado(1:4,:))/4;

[x2, y2, z2]=sphere(50);
surf(app.UIAxes,12.5*x2 + xyz(1),12.5*y2 + xyz(2),12.5*z2 + xyz(3)+12.5);

view(app.UIAxes,3)
axis(app.UIAxes,[-90 90 -90 90 -100 100])
app.UIAxes.CameraPosition=[-50 -50 50 ];
drawnow;

      end
      function pintar_STS_sano_3D(app)
     
        % A cada conjunto de datos se le resta x grados para asegurarse de que se 
        % muestra bien el movimiento (RESTAR ANTES DE USAR FUNCION MUESTRAS).
        % La funcion muestras genera 10k puntos de datos que le insertes.
        muestrasTronco = [79.78 79.15 77.66 74.83 70.39 64.77 58.62 53.20 49.52 48.22 49.40 52.66 57.55 63.44 69.72 75.90 81.33 85.65 88.86 91 92.49];
        muestrasTronco = muestrasTronco -90;
        muestrasTronco = app.muestras(muestrasTronco);
        
        muestrasCadera = [135.25 134.57 133.24 130.87 126.94 121.54 115.70 111.60 110.88 113.73 119.39 126.81 135.35 144.33 153.19 161.49 168.60 174.32 178.68 181.56 183.40];
        muestrasCadera = muestrasCadera -180;
        muestrasCadera = app.muestras(muestrasCadera);
        
        muestrasRodilla = [94.61 94.53 94.56 94.57 94.68 95.06 96.02 97.89 101.08 105.90 112.47 120.32 129.07 138.21 147.20 155.75 163.11 169.06 173.46 176.22 177.86];
        muestrasRodilla = muestrasRodilla -180;
        muestrasRodilla = app.muestras(muestrasRodilla);
        
        % Se observo que el tobillo representaba mejor el movimiento si este se
        % dejaba sin rotacion
        %mT = [105.75 105.56 105.23 104.75 104.10 103.26 102.21 101.03 99.93 99.44 100.28 101.68 103.44 105.30 107.19 108.78 110.21 111.12 111.59 111.74]
        muestrasTobillo = app.muestras([0 0 0]);
        
        % Este for dibuja de forma continuada el movimiento del muñeco asegurandose
        % de borrarlo cada vez antes de pintar de nuevo
        
        for i = 30:100
            cla(app.UIAxes)
                app.cadenaCinematicaSTSSana([0,0,-muestrasTronco(i*100)],[0,0,muestrasCadera(i*100)],[0,0,-muestrasRodilla(i*100)],[0,0,-muestrasTobillo(i*100)]);
            pause(0.02)
        end
      end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ContinuarButton.Enable = "off";

            pintar_STS_sano_3D(app)

            app.ContinuarButton.Enable = "on";

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
         delete(app)
         ventana_inicio
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
             delete(app)
            ejercicio_curva
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Representación del movimiento')
            app.PautaspararealizarelejercicioSTSLabel = uilabel(app.UIFigure);
            app.PautaspararealizarelejercicioSTSLabel.Text = 'Pautas para realizar el ejercicio STS';
            app.ComienzadesdeunaposicinsentadaLabel = uilabel(app.UIFigure);
            app.ComienzadesdeunaposicinsentadaLabel.Text = '- Comienza desde una posición sentada ';
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Screenshot 2022-11-13 at 14.13.57.png';
            app.Lasrodillasdebenestarflexionadas90Label = uilabel(app.UIFigure);
            app.Lasrodillasdebenestarflexionadas90Label.Text = '- Las rodillas deben estar flexionadas 90º ';
            app.AmbospiesdebenmirarhaciaelfrenteLabel = uilabel(app.UIFigure);
            app.AmbospiesdebenmirarhaciaelfrenteLabel.Text = '- Ambos pies deben mirar hacia el frente';
            app.LasmanossedebencolocarseencimadelosmuslosLabel = uilabel(app.UIFigure);
            app.LasmanossedebencolocarseencimadelosmuslosLabel.Text = '- Las manos se deben colocarse encima de los muslos ';
            app.paranoinfluirenelmovimientoLabel = uilabel(app.UIFigure);
            app.paranoinfluirenelmovimientoLabel.Text = 'para no influir en el movimiento';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pautas_ejercicio_sts

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end