classdef ejercicio_curva < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FiltrarButton       matlab.ui.control.Button
        Image               matlab.ui.control.Image
        ComenzarButton      matlab.ui.control.Button
        ContinuarButton     matlab.ui.control.Button
        Slider              matlab.ui.control.Slider
        MOVIMIENTOSTSLabel  matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
properties (Access = private)
   muestrasTronco = [79.78 79.15 77.66 74.83 70.39 64.77 58.62 53.20 49.52 48.22 49.40 52.66 57.55 63.44 69.72 75.90 81.33 85.65 88.86 91 92.49]
  
   Informe

   tiempo = 0;

   curva_tobillo_sts_filtrada = 0;
   curva_rodilla_sts_filtrada = 0;
   curva_cadera_sts_filtrada = 0;

   curva_tobillo_sts_ruido = 0;
   curva_rodilla_sts_ruido = 0;
   curva_cadera_sts_ruido = 0;
end


methods (Access = private)
    function modelRotated = applyRotationNx3(~,model,displacement,R)
    %esta funcion nos permite rotar un segmento a partir de una matriz de
    %rotacion.
    
    %applyRotationNx3(modelo que quieres rotar, eje de rotacion, matriz de rotacion)
    modelRotated = R*(model-repmat(displacement,size(model,1),1))';
    modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
    end
    
    function R = rotationMatrix(~,yaw,pitch,roll)
        %esta funcion nos permite formar una matriz de rotacion a partir de los
        %angulos que le demos
        matrixYaw = [cosd(yaw),-sind(yaw),0;...
            sind(yaw),cosd(yaw),0;0,0,1];
        matrixPitch = [cosd(pitch),0,sind(pitch);...
            0,1,0;-sind(pitch),0,cosd(pitch)];
        matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
            0,sind(roll),cosd(roll)];
        R = matrixYaw*matrixPitch*matrixRoll;
    end
    
    
    function muestras = muestras(~,curva)

    ejex_sano= linspace(0,100,length(curva));
    pp_sano=makima(ejex_sano,curva);
    
    muestras = ppval(pp_sano,linspace(0,100,10000));
    end
  function cadenaCinematicaSTSProtesis(app,YPRtronco,YPRmuslo,YPRpantorrilla,YPRpie)
    % Plantilla de como tienen que ponerse los datos para que se vea bien el
    % movimiento:
    % YPRtronco = [0,0, flexion/extension] (- flexion/ + extension)
    % YPRmuslo = [0,0,flexion/extension] (+ flexion/ - extension)
    % YPRrodilla = [0,0,flexion] (solo - porque la rodilla no hace extension)
    % YPRtobillo = [0,0,flexion/extension] (- flexion dorsal/ + flexion plantar)
    
    fTorso = [1,2,6,5;2,3,7,6;3,4,8,7;1,4,8,5;1,2,3,4;5,6,7,8];
    f = [1,2,6,5;2,4,8,6;3,4,8,7;1,3,7,5;1,2,4,3;5,6,8,7];
    color = [0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7];
    color3= [0 1 0;0 1 0; 0 1 0;0 1 0;0 1 0;0 1 0];
    
    %Le damos los puntos de cada solido
    cuello=[7 30 66; 43 30 66; 7 10 66; 43 10 66;21.5 25 71; 28.5 25 71; 21.5 15 71; 28.5 15 71 ];
    torso=[10 30 17; 40 30 17; 40 10 17; 10 10 17; 10 30 50; 40 30 50; 40 10 50; 10 10 50];
    Pecho=[7 30 50; 43 30 50; 7 7 50; 43 7 50; 7 30 66; 43 30 66; 7 7 66; 43 7 66];
    brazoizq=[43 25 66; 51 25 66; 43 15 66; 51 15 66; 43 25 -5; 51 25 -5; 43 15 -5; 51 15 -5];
    brazoder=[7 25 66; -1 25 66; 7 15 66; -1 15 66; 7 25 -5; -1 25 -5; 7 15 -5; -1 15 -5];
    cadera=[10 30 17;40 30 17;10 10 17;40 10 17;7 32 1;43 32 1;7 10 1;43 10, 1];
    musloder=[7 32 1;25 32 1;7 10 1;25 10 1;9 25 -15;23 25 -15;9 10 -15;23 10 -15];
    protesisdermuslo =[8 26 -15;24 26 -15;8 9 -15;24 9 -15;10.2 25 -42; 22.9 25 -42; 10.2 10 -42; 22.9 10 -42];
    pantorrilladerprotesis=[15 16 -48;17 16 -48;15 18 -48;17 18 -48;15 16 -82;17 16 -82;15 18 -82;17 18 -82];
    pieder=[10 25 -82;22 25 -82;10 0 -82;22 0 -82;10 25 -91;22 25 -91;10 0 -91;22 0 -91];
    musloizq=[25 32 1;43 32 1;25 10 1;42 10 1;27 25 -49;41 25 -49;27 10 -49;41 10, -49];
    pantorrillaizq=[27 25 -49;41 25 -49;27 10 -49;41 10 -49;28 25 -82;40 25 -82;28 10 -82;40 10, -82];
    pieizq=[28 25 -82;40 25 -82;28 0 -82;40 0 -82;28 25 -91;40 25 -91;28 0 -91;40 0 -91];
    
    %Con los datos del YPR sacamos matriz de rotacion con respecto a los angulos
    Rtronco = app.rotationMatrix(YPRtronco(1),YPRtronco(2),YPRtronco(3));
    Rmuslo = app.rotationMatrix(YPRmuslo(1),YPRmuslo(2),YPRmuslo(3));
    Rpantorilla = app.rotationMatrix(YPRpantorrilla(1),YPRpantorrilla(2),YPRpantorrilla(3));
    Rpie = app.rotationMatrix(YPRpie(1),YPRpie(2),YPRpie(3));
    
    %Rotamos cada segmento del cuerpo. Importante asegurarnos de mantener la
    %cadena para conservar la union entre partes. Tambien hay que poner los
    %puntos que serviran como eje de rotacion.
    %Plantilla:
    % musculoRotado = applyRotationNx3(musculoARotar, ejeDeRotacion, matrizDeRotacion)
    caderaRotado = app.applyRotationNx3(cadera,...
        (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
    torsoRotado = app.applyRotationNx3(torso,...
        (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
    pechoRotado = app.applyRotationNx3(Pecho,...
        (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
    brazoderRotado = app.applyRotationNx3(brazoder,...
        (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
    brazoizqRotado = app.applyRotationNx3(brazoizq,...
        (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
    cuelloRotado = app.applyRotationNx3(cuello,...
        (torso(1,:)+torso(2,:)+torso(3,:)+torso(4,:))/4,Rtronco);
    
    musloderRotado = app.applyRotationNx3(musloder,...
        (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmuslo);
    musloderProtesisRotado = app.applyRotationNx3(protesisdermuslo,...
        (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmuslo);
    pantorrilladerRotado = app.applyRotationNx3(app.applyRotationNx3(pantorrilladerprotesis,...
        (pantorrilladerprotesis(1,:)+pantorrilladerprotesis(2,:)+pantorrilladerprotesis(3,:)+pantorrilladerprotesis(4,:))/4,Rpantorilla), ...
        (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmuslo);
    piederRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(pieder,...
        (pantorrilladerprotesis(5,:)+pantorrilladerprotesis(6,:)+pantorrilladerprotesis(7,:)+pantorrilladerprotesis(8,:))/4,Rpie), ...
        (pantorrilladerprotesis(1,:)+pantorrilladerprotesis(2,:)+pantorrilladerprotesis(3,:)+pantorrilladerprotesis(4,:))/4,Rpantorilla), ...
        (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmuslo);
    
    musloizqRotado = app.applyRotationNx3(musloizq,...
        (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmuslo);
    pantorrillaizqRotado = app.applyRotationNx3(app.applyRotationNx3(pantorrillaizq,...
        (pantorrillaizq(1,:)+pantorrillaizq(2,:)+pantorrillaizq(3,:)+pantorrillaizq(4,:))/4,Rpantorilla), ...
        (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmuslo);
    pieizqRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(pieizq,...
        (pantorrillaizq(5,:)+pantorrillaizq(6,:)+pantorrillaizq(7,:)+pantorrillaizq(8,:))/4,Rpie), ...
        (pantorrillaizq(1,:)+pantorrillaizq(2,:)+pantorrillaizq(3,:)+pantorrillaizq(4,:))/4,Rpantorilla), ...
        (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmuslo);
    
    %Montamos la figura y la pintamos
    
    patch(app.UIAxes2,'faces',f,'Vertices',cuelloRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',fTorso,'Vertices',torsoRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',pechoRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',brazoderRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',brazoizqRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',caderaRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'vertices',musloderRotado,'FaceVertexCData',color,'FaceColor','flat')
    patch(app.UIAxes2,'faces',f,'vertices',musloderProtesisRotado,'FaceVertexCData',color3,'FaceColor','flat')
    patch(app.UIAxes2,'faces',f,'vertices',pantorrilladerRotado,'FaceVertexCData',color3,'FaceColor','flat')
    patch(app.UIAxes2,'faces',f,'vertices',piederRotado,'FaceVertexCData',color3,'FaceColor','flat')
    patch(app.UIAxes2,'faces',f,'Vertices',musloizqRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',pantorrillaizqRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',pieizqRotado,'FaceVertexCData',color,'FaceColor','flat');
    hold(app.UIAxes2,'on')
    
    xyz = sum(cuelloRotado(1:4,:))/4;
    
    [x2, y2, z2]=sphere(50);
    surf(app.UIAxes2,12.5*x2 + xyz(1),12.5*y2 + xyz(2),12.5*z2 + xyz(3)+12.5);
    hold(app.UIAxes2,'on')
    
    xyz = sum(musloderProtesisRotado(5:end,:))/4;
    
    [x2, y2, z2]=sphere(20);
    surf(app.UIAxes2,6.5*x2 + xyz(1),6.5*y2 + xyz(2),6.5*z2 + xyz(3));
    
    view(app.UIAxes2,3)
        axis(app.UIAxes2,[-90 90 -90 90 -100 100])
        app.UIAxes2.CameraPosition=[-50 -50 50 ];
        drawnow;
    axis image
    
  end

    function pintar_protesis_sts_3D(app)
      
    % A cada conjunto de datos se le resta x grados para asegurarse de que se 
    % muestra bien el movimiento (RESTAR ANTES DE USAR FUNCION MUESTRAS).
    % La funcion muestras genera 10k puntos de datos que le insertes.
    app.muestrasTronco = [79.78 79.15 77.66 74.83 70.39 64.77 58.62 53.20 49.52 48.22 49.40 52.66 57.55 63.44 69.72 75.90 81.33 85.65 88.86 91 92.49]
    app.muestrasTronco = app.muestrasTronco -90
    app.muestrasTronco = app.muestras(app.muestrasTronco)
    
    muestrasCadera = [135.25 134.57 133.24 130.87 126.94 121.54 115.70 111.60 110.88 113.73 119.39 126.81 135.35 144.33 153.19 161.49 168.60 174.32 178.68 181.56 183.40];
    muestrasCadera = muestrasCadera -180
    muestrasCadera = app.muestras(muestrasCadera)
    
    muestrasRodilla = [94.61 94.53 94.56 94.57 94.68 95.06 96.02 97.89 101.08 105.90 112.47 120.32 129.07 138.21 147.20 155.75 163.11 169.06 173.46 176.22 177.86]
    muestrasRodilla = muestrasRodilla -180
    muestrasRodilla = app.muestras(muestrasRodilla)
    
    % Se observo que el tobillo representaba mejor el movimiento si este se
    % dejaba sin rotacion
    %mT = [105.75 105.56 105.23 104.75 104.10 103.26 102.21 101.03 99.93 99.44 100.28 101.68 103.44 105.30 107.19 108.78 110.21 111.12 111.59 111.74]
    muestrasTobillo = app.muestras([0 0 0])
    
    % Este for dibuja de forma continuada el movimiento del muñeco asegurandose
    % de borrarlo cada vez antes de pintar de nuevo
        for i = 30:100
        cla(app.UIAxes2)
            app.cadenaCinematicaSTSProtesis([0,0,-app.muestrasTronco(i*100)],[0,0,muestrasCadera(i*100)],[0,0,-muestrasRodilla(i*100)],[0,0,-muestrasTobillo(i*100)]);
        pause(0.02)
        end


 end
    
 function [pfinal,curva_ruido_seno,ejex10k]=funcion_aleatoriedad_rodilla(~,curva1)

    %Esta función generará curvas con aleatoriedad 
    %p.e.: funcion_aleatoriedad(curva1, 0.2, 1 ,0.6)
    %__________________________________________________________________________
    
    %Generar cuatro valores aleatorios de una sola vez (vector)
    r = 0.1 + (0.3-0.1)* rand(1,1);
    
    v = rand(1,4) * r + 0.9;
    
    %Primera parte de la curva la multiplico por primer valor de variabilidad
    f1=curva1(1:4)*v(1);
    
    %Segunda parte de la curva la multiplico por segundo valor de variabilidad
    f2=curva1(5:9)*v(2);
    
    %Tecera parte de la curva la multiplico por tercer valor de variabilidad
    f3=curva1(10:13)*v(3);
    
    %Cuarta parte de la curva la multiplico por cuarto valor de variabilidad
    f4=curva1(14:end)*v(4);
    
    ejex_normal=linspace(0,4,length(curva1));
    
    %Aquí dura 4 segundos, lo ideal.
    %plot(ejex_normal,curva1);
    
    %Generar curva con las fases
    
    curva=[f1 f2 f3 f4];
    
    %__________________________________________________________________________
    
    %ALEATORIEDAD TIEMPO
    
    %Primer punto
    minim = 0.7;
    maxim = 1.5;
    p1 = minim + (maxim-minim).*rand(1,1);
    %ejex_primera_duracion = linspace(0,duracion,length(curva1));
    
    %Segundo punto
    minim = 1.7;
    maxim = 2.5;
    p2 = minim + (maxim-minim).*rand(1,1);
    %ejex_segunda_duracion = linspace(0,duracion,length(curva1));
    
    %Tercer punto
    minim = 2.7;
    maxim = 3;
    p3 = minim + (maxim-minim).*rand(1,1);
    %ejex_tercera_duracion= linspace(0,duracion,length(curva1));
    
    %Punto final/cuatro
    minim = 3.7;
    maxim = 4.5;
    pfinal = minim + (maxim-minim).*rand(1,1);
    %ejex_cuarta_duracion = linspace(0,duracion,length(curva1));
    
    T= pfinal /length(curva1);
    
    %__________________________________________________________________________
    %VARIABILIDAD ANCHURA EJE X 
    
    ejef1=linspace(0,p1,length(f1));
    ejef2=linspace(p1+T,p2,length(f2));
    ejef3=linspace(p2+T,p3,length(f3));
    ejef4=linspace(p3+T,pfinal,length(f4));
    
    %__________________________________________________________________________
    
    %Fórmula que nos va a dar la curva (no es importante saber mucho más)
    %pp = makima(ejex,curva);
    %plot([ejef1 ejef2 ejef3 ejef4],curva)
    
    %Reajustar curva con la interpolación de los nuevos valores: interp1
    %Sobre nuevo ejex calculamos valores de amplitud nuevos
    
    %Eje nuevo "destrozado" de la curva nueva
    ejex_nuevo = linspace(0,pfinal,length(curva));
    
    %La nueva curva tiene la señal nueva estirada o contraída equispaciada
    
    curva_nueva = interp1([ejef1 ejef2 ejef3 ejef4], curva, ejex_nuevo);
    
    pp = makima(ejex_nuevo,curva_nueva);
    
    %Lo aplicamos a nuestra curva: curva interpolada
    curva10k = ppval(pp,linspace(0,pfinal,10000));
    
    %curva10kantigua=ppval(pp3,linspace(0,100,10000));
    
    ejex10k=linspace(0,pfinal,length(curva10k));
    %__________________________________________________________________________
    
    % A partir de aquí hay que meter ruido 
    %Valores que vayan de -var a var
    %Quiero 100000 valores random, pero siempre seran entre 0 y 1
    r_r=rand(1,length(curva10k));
    
    %Valores random de ruido
    %crea valores random de 2 a 9. Pq queda bien 
    var_ruido= 2 + (5+2)*rand(1,1);
    
    %Aplico aleatoreidad, pero a un vector.
    % Cada valor de r va a ser multiplicado por un nº random entre 2 y 9
    % (var_ruido)
    al = var_ruido*r_r - var_ruido/2;
    
    %Creo función de seno
    
    %Esta es la función del seno genércia
    %f(t) = A*sin(w*t + theta)
    %Asignamos valores a las variables de la función del seno
    
    % Frecuencia: la ponemos en 60 Hz
        freq = 60*pi; 
     
    % Frecuencia angular
        w = 2*pi*freq; 
     
    % Amplitud: le asignamos los valores de aleatoriedad previamente generados
        A = al;  
     
    % Valores de t
        t = linspace(0,pi,10000);  
     
    % Función del seno con sus respectivos valores de frecuencia y amplitud
        seno60 = A .* sin(w*t);
     
    % Sine wave plot
      %plot(t, seno60), title("Sine Wave"),
      %xlabel("Time"), ylabel("Sine wave function")
    
    %Ahora no van entre 0 y 1, sino entre -0.8 y 0.8
    %Se obtiene una señal con ruido
    curva_ruido_seno = curva10k + seno60;
  
     %plot(app.UIAxes_2,ejex10k,curva_ruido_seno,'b')
    
    %plot(ejex10k,curva10k,'r'), hold on, plot(ejex_normal,curva1,'b');

end
function pinta(app)

    app.muestrasTronco = [79.78 79.15 77.66 74.83 70.39 64.77 58.62 53.20 49.52 48.22 49.40 52.66 57.55 63.44 69.72 75.90 81.33 85.65 88.86 91 92.49]
    app.muestrasTronco = app.muestrasTronco -90
    app.muestrasTronco = app.muestras(app.muestrasTronco)
    
    muestrasCadera = [135.25 134.57 133.24 130.87 126.94 121.54 115.70 111.60 110.88 113.73 119.39 126.81 135.35 144.33 153.19 161.49 168.60 174.32 178.68 181.56 183.40];
    muestrasCadera = muestrasCadera -180
    muestrasCadera = app.muestras(muestrasCadera)
    
    muestrasRodilla = [94.61 94.53 94.56 94.57 94.68 95.06 96.02 97.89 101.08 105.90 112.47 120.32 129.07 138.21 147.20 155.75 163.11 169.06 173.46 176.22 177.86]
    muestrasRodilla = muestrasRodilla -180
    muestrasRodilla = app.muestras(muestrasRodilla)
    
    muestrasTobillo = app.muestras([0 0 0])


    curva_tobillo_sts = [105.75 105.56 105.23 104.75 104.10 103.26 102.21 101.03 99.93 99.44 100.28 101.68 103.44 105.30 107.19 108.78 110.21 111.12 111.59 111.74];
    [app.tiempo,app.curva_tobillo_sts_ruido,ejex10k]=funcion_aleatoriedad_rodilla(app,curva_tobillo_sts);
    
    curva_rodilla_sts = [94.61 94.53 94.56 94.57 94.68 95.06 96.02 97.89 101.08 105.90 112.47 120.32 129.07 138.21 147.20 155.75 163.11 169.06 173.46 176.22 177.86];
    [app.tiempo,app.curva_rodilla_sts_ruido,ejex10k]=funcion_aleatoriedad_rodilla(app,curva_rodilla_sts);
    
    
    curva_angulo_cadera_sts = [135.25 134.57 133.24 130.87 126.94 121.54 115.70 111.60 110.88 113.73 119.39 126.81 135.35 144.33 153.19 161.49 168.60 174.32 178.68 181.56 183.40];
    [app.tiempo,app.curva_cadera_sts_ruido,ejex10k]=funcion_aleatoriedad_rodilla(app,curva_angulo_cadera_sts);
    
    
    paso=100;
    for i=1:paso:length(ejex10k)-paso-1
    
    cla(app.UIAxes2)
    app.cadenaCinematicaSTSProtesis([0,0,-app.muestrasTronco(i)],[0,0,muestrasCadera(i)],[0,0,-muestrasRodilla(i)],[0,0,-muestrasTobillo(i)]);
    
    %axis(app.UIAxes_2,[0 3.2 80 150])
    axis(app.UIAxes_2,[ejex10k(1) ejex10k(end) min(app.curva_rodilla_sts_ruido)-10 max(app.curva_rodilla_sts_ruido)+10])
    plot(app.UIAxes_2,ejex10k(1:i+paso),app.curva_rodilla_sts_ruido(1:i+paso),'b')
    
    axis(app.UIAxes,[ejex10k(1) ejex10k(end) min(app.curva_tobillo_sts_ruido)-10 max(app.curva_tobillo_sts_ruido)+10])
    plot(app.UIAxes,ejex10k(1:i+paso),app.curva_tobillo_sts_ruido(1:i+paso),'b')
    
    axis(app.UIAxes_3,[ejex10k(1) ejex10k(end) min(app.curva_cadera_sts_ruido)-10 max(app.curva_cadera_sts_ruido)+10])
    plot(app.UIAxes_3,ejex10k(1:i+paso),app.curva_cadera_sts_ruido(1:i+paso),'b')
    
    app.Slider.Value = (i+paso)/100;
    pause(0.001);
    end
end
function [eje_66,curva_filtrada] = funcion_filtrar(~,curva_ruido)

    Fs = 1000;                                                  % Frecuencia de muestreo
    Fn = Fs/2;                                                  % Frecuencia de Nyquist
    Wp = 59/Fn;                                         % Frecuencia de paso normalizada
    Ws = 61/Fn;                                         % Frecuencia de corte normalizada
    Rp =   1;                                                   % Passband Ripple (dB)
    Rs = 150;                                                   % Stopband Ripple (dB)
    [n,Ws] = cheb2ord(Wp,Ws,Rp,Rs);                             % Filter Order
    [z,p,k] = cheby2(n,Rs,Ws);                                  % Filter Design
    [sosbp,gbp] = zp2sos(z,p,k);                                % Convert To Second-Order-Section For Stability
                                   % Filter Bode Plot
    curva_filtrada = filtfilt(sosbp, gbp, curva_ruido);    % Filter Signal
    
    eje_66 = linspace(0,1,length(curva_filtrada));
    
    %plot(eje_66,curva_filtrada);
end

function pintar_filtrar_curva(app)
   
    [eje_filtrado,app.curva_rodilla_sts_filtrada] =funcion_filtrar(app,app.curva_rodilla_sts_ruido);
    [eje_filtrado,app.curva_tobillo_sts_filtrada] =funcion_filtrar(app,app.curva_tobillo_sts_ruido);
    [eje_filtrado,app.curva_cadera_sts_filtrada] =funcion_filtrar(app,app.curva_cadera_sts_ruido);
    cla(app.UIAxes)
    cla(app.UIAxes_3)
    cla(app.UIAxes_2)
    
    paso=100;
    for i=1:paso:length(eje_filtrado)-paso-1
          
    axis(app.UIAxes_2,[eje_filtrado(1) eje_filtrado(end) min(app.curva_rodilla_sts_filtrada)-10 max(app.curva_rodilla_sts_filtrada)+10])
    plot(app.UIAxes_2,eje_filtrado(1:i+paso),app.curva_rodilla_sts_filtrada(1:i+paso),'b')
    
    axis(app.UIAxes,[eje_filtrado(1) eje_filtrado(end) min(app.curva_tobillo_sts_filtrada)-10 max(app.curva_tobillo_sts_filtrada)+10])
    plot(app.UIAxes,eje_filtrado(1:i+paso),app.curva_tobillo_sts_filtrada(1:i+paso),'b')
    
    axis(app.UIAxes_3,[eje_filtrado(1) eje_filtrado(end) min(app.curva_cadera_sts_filtrada)-10 max(app.curva_cadera_sts_filtrada)+10])
    plot(app.UIAxes_3,eje_filtrado(1:i+paso),app.curva_cadera_sts_filtrada(1:i+paso),'b')
    
    pause(0.001);
    end

end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.ContinuarButton.Enable = "off";
         app.FiltrarButton.Enable = "off";
         
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
           delete(app)
           Pautas_ejercicio_sts
            
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
app.ContinuarButton.Enable = "off";
app.ComenzarButton.Enable = "off";
app.FiltrarButton.Enable = "off";

app.Informe = Informe_biomecanico_sts(app,app.tiempo,app.curva_tobillo_sts_filtrada, app.curva_rodilla_sts_filtrada, app.curva_cadera_sts_filtrada);
           delete(app)
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
         app.ContinuarButton.Enable = "off";
         app.ComenzarButton.Enable = "off";
         app.FiltrarButton.Enable = "off";
         pinta(app)
         app.FiltrarButton.Enable = "on";

        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            app.ContinuarButton.Enable = "off";
            app.ComenzarButton.Enable = "off";
            app.FiltrarButton.Enable = "off";
            pintar_filtrar_curva(app)
         app.ContinuarButton.Enable = "on";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Curva del tobillo')
            xlabel(app.UIAxes, '% de movimiento')
            ylabel(app.UIAxes, 'Ángulo tobillo')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Curva de la rodilla')
            xlabel(app.UIAxes_2, '% de movimiento')
            ylabel(app.UIAxes_2, 'Ángulo rodilla')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Curva de la cadera')
            xlabel(app.UIAxes_3, '% de movimiento')
            ylabel(app.UIAxes_3, 'Ángulo cadera')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Representación del movimiento')
            ylabel(app.UIAxes2, {''; ''})
            zlabel(app.UIAxes2, 'Z')
            app.MOVIMIENTOSTSLabel = uilabel(app.UIFigure);
            app.MOVIMIENTOSTSLabel.Text = 'MOVIMIENTO STS';
            app.Slider = uislider(app.UIFigure);
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.ComenzarButton = uibutton(app.UIFigure, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Screenshot 2022-11-13 at 14.13.57.png';
            app.FiltrarButton = uibutton(app.UIFigure, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ejercicio_curva

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end