classdef ejercicio_curva_carrera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        FiltrarButton    matlab.ui.control.Button
        Image            matlab.ui.control.Image
        ComenzarButton   matlab.ui.control.Button
        ContinuarButton  matlab.ui.control.Button
        Slider           matlab.ui.control.Slider
        CARRERALabel     matlab.ui.control.Label
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes_3         matlab.ui.control.UIAxes
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    Informe

    tiempo = 0;

    curva_tobillo_carrera_filtrada = 0;
    curva_rodilla_carrera_filtrada = 0;
    curva_cadera_carrera_filtrada = 0;

    curva_tobillo_carrera_ruido = 0;
    curva_rodilla_carrera_ruido = 0;
    curva_cadera_carrera_ruido = 0;

end
methods (Access = private)
    
    function cadenaCinematicaCarreraProtesis(app,YPRmusloder,YPRpantorrillader,YPRpieder,YPRmusloizq,YPRpantorrillaizq,YPRpieizq)
    % Plantilla de como tienen que ponerse los datos para que se vea bien el
    % movimiento:
    % YPRmuslo = [0,0,flexion/extension] (- flexion/ + extension)
    % YPRrodilla = [0,0,flexion] (solo - porque la rodilla no hace extension)
    % YPRtobillo = [0,0,flexion/extension] (- flexion dorsal/ + flexion plantar)
    
    fTorso = [1,2,6,5;2,3,7,6;3,4,8,7;1,4,8,5;1,2,3,4;5,6,7,8];
    f = [1,2,6,5;2,4,8,6;3,4,8,7;1,3,7,5;1,2,4,3;5,6,8,7];
    color = [0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7;0.7,0.7,0.7];
    color3= [0 1 0;0 1 0; 0 1 0;0 1 0;0 1 0;0 1 0];
    
    %Le damos los puntos de cada solido
    cuello=[7 30 66; 43 30 66; 7 10 66; 43 10 66;21.5 25 71; 28.5 25 71; 21.5 15 71; 28.5 15 71 ];
    torso=[10 30 17; 40 30 17; 40 10 17; 10 10 17; 10 30 50; 40 30 50; 40 10 50; 10 10 50];
    Pecho=[7 30 50; 43 30 50; 7 7 50; 43 7 50; 7 30 66; 43 30 66; 7 7 66; 43 7 66];
    brazoizq=[43 25 66; 51 25 66; 43 15 66; 51 15 66; 43 25 -5; 51 25 -5; 43 15 -5; 51 15 -5];
    brazoder=[7 25 66; -1 25 66; 7 15 66; -1 15 66; 7 25 -5; -1 25 -5; 7 15 -5; -1 15 -5];
    cadera=[10 30 17;40 30 17;10 10 17;40 10 17;7 32 1;43 32 1;7 10 1;43 10, 1];
    musloder=[7 32 1;25 32 1;7 10 1;25 10 1;9 25 -15;23 25 -15;9 10 -15;23 10 -15];
    protesisdermuslo =[8 26 -15;24 26 -15;8 9 -15;24 9 -15;10.2 25 -42; 22.9 25 -42; 10.2 10 -42; 22.9 10 -42];
    pantorrilladerprotesis=[15 16 -48;17 16 -48;15 18 -48;17 18 -48;15 16 -82;17 16 -82;15 18 -82;17 18 -82];
    pieder=[10 25 -82;22 25 -82;10 0 -82;22 0 -82;10 25 -91;22 25 -91;10 0 -91;22 0 -91];
    musloizq=[25 32 1;43 32 1;25 10 1;42 10 1;27 25 -49;41 25 -49;27 10 -49;41 10, -49];
    pantorrillaizq=[27 25 -49;41 25 -49;27 10 -49;41 10 -49;28 25 -82;40 25 -82;28 10 -82;40 10, -82];
    pieizq=[28 25 -82;40 25 -82;28 0 -82;40 0 -82;28 25 -91;40 25 -91;28 0 -91;40 0 -91];
    
    %Con los datos del YPR sacamos matriz de rotacion con respecto a los angulos
    Rmusloder = app.rotationMatrix(YPRmusloder(1),YPRmusloder(2),YPRmusloder(3));
    Rpantorillader = app.rotationMatrix(YPRpantorrillader(1),YPRpantorrillader(2),YPRpantorrillader(3));
    Rpieder = app.rotationMatrix(YPRpieder(1),YPRpieder(2),YPRpieder(3));
    
    %Rotamos cada segmento del cuerpo. Importante asegurarnos de mantener la
    %cadena para conservar la union entre partes. Tambien hay que poner los
    %puntos que serviran como eje de rotacion.
    %Plantilla:
    % musculoRotado = applyRotationNx3(musculoARotar, ejeDeRotacion, matrizDeRotacion)
    musloderRotado = app.applyRotationNx3(musloder,...
        (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmusloder);
    musloderProtesisRotado = app.applyRotationNx3(protesisdermuslo,...
        (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmusloder);
    
    pantorrilladerRotado = app.applyRotationNx3(app.applyRotationNx3(pantorrilladerprotesis,...
        (pantorrilladerprotesis(1,:)+pantorrilladerprotesis(2,:)+pantorrilladerprotesis(3,:)+pantorrilladerprotesis(4,:))/4,Rpantorillader), ...
        (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmusloder);
    
    piederRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(pieder,...
        (pantorrilladerprotesis(5,:)+pantorrilladerprotesis(6,:)+pantorrilladerprotesis(7,:)+pantorrilladerprotesis(8,:))/4,Rpieder), ...
        (pantorrilladerprotesis(1,:)+pantorrilladerprotesis(2,:)+pantorrilladerprotesis(3,:)+pantorrilladerprotesis(4,:))/4,Rpantorillader), ...
        (musloder(1,:)+musloder(2,:)+musloder(3,:)+musloder(4,:))/4,Rmusloder);
    
    %Conseguimos la matriz de rotacion de la pierna izquierda
    Rmusloizq = app.rotationMatrix(YPRmusloizq(1),YPRmusloizq(2),YPRmusloizq(3));
    Rpantorillaizq = app.rotationMatrix(YPRpantorrillaizq(1),YPRpantorrillaizq(2),YPRpantorrillaizq(3));
    Rpieizq = app.rotationMatrix(YPRpieizq(1),YPRpieizq(2),YPRpieizq(3));
    
    %Rotamos los segmentos de la pierna izquierda
    musloizqRotado = app.applyRotationNx3(musloizq,...
        (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmusloizq);
    
    pantorrillaizqRotado = app.applyRotationNx3(app.applyRotationNx3(pantorrillaizq,...
        (pantorrillaizq(1,:)+pantorrillaizq(2,:)+pantorrillaizq(3,:)+pantorrillaizq(4,:))/4,Rpantorillaizq), ...
        (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmusloizq);
    pieizqRotado = app.applyRotationNx3(app.applyRotationNx3(app.applyRotationNx3(pieizq,...
        (pantorrillaizq(5,:)+pantorrillaizq(6,:)+pantorrillaizq(7,:)+pantorrillaizq(8,:))/4,Rpieizq), ...
        (pantorrillaizq(1,:)+pantorrillaizq(2,:)+pantorrillaizq(3,:)+pantorrillaizq(4,:))/4,Rpantorillaizq), ...
        (musloizq(1,:)+musloizq(2,:)+musloizq(3,:)+musloizq(4,:))/4,Rmusloizq);
    
    % Rotamos los brazos con respecto al angulo de la cadera y lo dividimos 
    % para que sea menos exagerado que la cadera. Cogemos la cadera porque el
    % movimiento es similar a los muslos
    Rbrazoder = app.rotationMatrix(YPRmusloder(1),YPRmusloder(2),-YPRmusloder(3)/1.5);
    Rbrazoizq = app.rotationMatrix(YPRmusloizq(1),YPRmusloizq(2),YPRmusloder(3)/1.5);
    
    brazoderRotado = app.applyRotationNx3(brazoder,...
        (brazoder(1,:)+brazoder(2,:)+brazoder(3,:)+brazoder(4,:))/4,Rbrazoder);
    brazoizqRotado = app.applyRotationNx3(brazoizq,...
        (brazoizq(1,:)+brazoizq(2,:)+brazoizq(3,:)+brazoizq(4,:))/4,Rbrazoizq);
    
    %Montamos la figura y la pintamos
    
    patch(app.UIAxes2,'faces',f,'Vertices',cuello,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',fTorso,'Vertices',torso,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',Pecho,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',brazoderRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',brazoizqRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',cadera,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'vertices',musloderRotado,'FaceVertexCData',color,'FaceColor','flat')
    patch(app.UIAxes2,'faces',f,'vertices',musloderProtesisRotado,'FaceVertexCData',color3,'FaceColor','flat')
    patch(app.UIAxes2,'faces',f,'vertices',pantorrilladerRotado,'FaceVertexCData',color3,'FaceColor','flat')
    patch(app.UIAxes2,'faces',f,'vertices',piederRotado,'FaceVertexCData',color3,'FaceColor','flat')
    patch(app.UIAxes2,'faces',f,'Vertices',musloizqRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',pantorrillaizqRotado,'FaceVertexCData',color,'FaceColor','flat');
    patch(app.UIAxes2,'faces',f,'Vertices',pieizqRotado,'FaceVertexCData',color,'FaceColor','flat');
    hold(app.UIAxes2,'on')
    [x, y, z]=sphere(50);
    surf(app.UIAxes2,12.5*x+25,12.5*y+20,12.5*z+81);
    hold(app.UIAxes2,'on')
    
    xyz = sum(musloderProtesisRotado(5:end,:))/4;
    [x2, y2, z2]=sphere(20);
    surf(app.UIAxes2,6.5*x2 + xyz(1),6.5*y2 + xyz(2),6.5*z2 + xyz(3));
    
    view(app.UIAxes2,3)
        axis(app.UIAxes2,[-90 90 -90 90 -100 100])
        app.UIAxes2.CameraPosition=[-50 -50 50 ];
        drawnow;
    axis image

end
function [pfinal,curva_ruido_seno,ejex10k]=funcion_aleatoriedad_rodilla(~,curva1)

    %Esta función generará curvas con aleatoriedad 
    %p.e.: funcion_aleatoriedad(curva1, 0.2, 1 ,0.6)
    %__________________________________________________________________________
    %Generar cuatro valores aleatorios de una sola vez (vector)
    var= 0.1 +(0.3-0.1)*rand(1,1);
    v = ones(1,4)+var;
    %Primera parte de la curva la multiplico por primer valor de variabilidad
    f1=curva1(1:4)*v(1);
    %Segunda parte de la curva la multiplico por segundo valor de variabilidad
    f2=curva1(5:9)*v(2);
    %Tecera parte de la curva la multiplico por tercer valor de variabilidad
    f3=curva1(10:13)*v(3);
    %Cuarta parte de la curva la multiplico por cuarto valor de variabilidad
    f4=curva1(14:end)*v(4);
    ejex_normal=linspace(0,4,length(curva1));
    %Aquí dura 4 segundos, lo ideal.
    %plot(ejex_normal,curva1);
    %Generar curva con las fases
    curva=[f1 f2 f3 f4];
    %ALEATORIEDAD TIEMPO
    %Primer punto
    minim = 0.7;
    maxim = 1.3;
    p1 = minim + (maxim-minim).*rand(1,1);
    %ejex_primera_duracion = linspace(0,duracion,length(curva1));
    
    %Segundo punto
    minim = 1.7;
    maxim = 2.5;
    p2 = minim + (maxim-minim).*rand(1,1);
    %ejex_segunda_duracion = linspace(0,duracion,length(curva1));
    
    %Tercer punto
    minim = 2.7;
    maxim = 3.5;
    p3 = minim + (maxim-minim).*rand(1,1);
    %ejex_tercera_duracion= linspace(0,duracion,length(curva1));
    
    %Punto final/cuatro
    minim = 3.8;
    maxim = 4.9;
    pfinal = minim + (maxim-minim).*rand(1,1);
    %ejex_cuarta_duracion = linspace(0,duracion,length(curva1));
    
    T= pfinal /length(curva1);
  %__________________________________________________________________________
    %VARIABILIDAD ANCHURA EJE X 
    ejef1=linspace(0,p1,length(f1));
    ejef2=linspace(p1+T,p2,length(f2));
    ejef3=linspace(p2+T,p3,length(f3));
    ejef4=linspace(p3+T,pfinal,length(f4));
    %Fórmula que nos va a dar la curva (no es importante saber mucho más)
    %pp = makima(ejex,curva);
    %plot([ejef1 ejef2 ejef3 ejef4],curva)
    %Reajustar curva con la interpolación de los nuevos valores: interp1
    %Sobre nuevo ejex calculamos valores de amplitud nuevos
    %Eje nuevo "destrozado" de la curva nueva
    ejex_nuevo = linspace(0,pfinal,length(curva));
    %La nueva curva tiene la señal nueva estirada o contraída equispaciada
    curva_nueva = interp1([ejef1 ejef2 ejef3 ejef4], curva, ejex_nuevo);
    pp = makima(ejex_nuevo,curva_nueva);
    %Lo aplicamos a nuestra curva: curva interpolada
    curva10k = ppval(pp,linspace(0,pfinal,10000));
    %curva10kantigua=ppval(pp3,linspace(0,100,10000));
    ejex10k=linspace(0,pfinal,length(curva10k));
    %__________________________________________________________________________
    
    % A partir de aquí hay que meter ruido 
    %Valores que vayan de -var a var
    %Quiero 100000 valores random, pero siempre seran entre 0 y 1
    r_r=rand(1,length(curva10k));
    %Valores random de ruido
    %crea valores random de 2 a 9. Pq queda bien 
    var_ruido= 2 + (5+2)*rand(1,1);
    %Aplico aleatoreidad, pero a un vector.
    % Cada valor de r va a ser multiplicado por un nº random entre 2 y 9
    % (var_ruido)
    al = var_ruido*r_r - var_ruido/2;
    %Esta es la función del seno genércia
    %f(t) = A*sin(w*t + theta)
    %Asignamos valores a las variables de la función del seno
    % Frecuencia: la ponemos en 60 Hz
        freq = 60*pi;  
    % Frecuencia angular
        w = 2*pi*freq; 
        A = al;  
        t = linspace(0,pi,10000);  
        seno60 = A .* sin(w*t);
    curva_ruido_seno = curva10k + seno60;

    end
    
    function R = rotationMatrix(~,yaw,pitch,roll)
        %esta funcion nos permite formar una matriz de rotacion a partir de los
        %angulos que le demos
        matrixYaw = [cosd(yaw),-sind(yaw),0;...
            sind(yaw),cosd(yaw),0;0,0,1];
        matrixPitch = [cosd(pitch),0,sind(pitch);...
            0,1,0;-sind(pitch),0,cosd(pitch)];
        matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
            0,sind(roll),cosd(roll)];
        R = matrixYaw*matrixPitch*matrixRoll;
    end
    
    function modelRotated = applyRotationNx3(~,model,displacement,R)
    %esta funcion nos permite rotar un segmento a partir de una matriz de
    %rotacion.
    
    %applyRotationNx3(modelo que quieres rotar, eje de rotacion, matriz de rotacion)
    modelRotated = R*(model-repmat(displacement,size(model,1),1))';
    modelRotated = modelRotated' + repmat(displacement,size(model,1),1);
    end
    
    function muestras = muestras(~,curva)

    ejex_sano= linspace(0,100,length(curva));
    pp_sano=makima(ejex_sano,curva);
    
    muestras = ppval(pp_sano,linspace(0,100,10000));
    end
    
    function pinta(app)
        
        muestrasCadera = app.muestras([20 17 12 5 -3 -14 -23 -28 -33 -29 -25 -17 -5  11 25 32 41 38 35 34 32 26 19]);

        muestrasRodilla = [176 176 175.5 173 172 174 175 172 165  155 120 95 60 57 63 73 93 117 151 163 172 175 177 177]
        muestrasRodilla = muestrasRodilla - 180
        muestrasRodilla = app.muestras(muestrasRodilla)

        muestrasTobillo = app.muestras([0 2 3 2 -2 -8 -11 -10 -7 3 5 4 3 4 4 3 1 1 0 -3 -3 -2 0])
        
        % Como sabemos que la zancada no es la misma entre pies en personas con
        % protesis, cogemos las muestras correspondientes al sano en la pierna
        % izquierda
        muestrasCaderaIzq = app.muestras([40 36 30 22 15  2 -11  -26 -33 -35 -34 -31 -25 -17 5 27 52 63 68  65 58 50 40]);
        muestrasCaderaIzq = [muestrasCaderaIzq(5000:10000) muestrasCaderaIzq(1:5000)]
        
        muestrasRodillaIzq = [152 151 150 151 156 161 165 166 158 147 120 100 76 53 48 54 67 85 100 115 130 149 152]
        muestrasRodillaIzq = muestrasRodillaIzq - 180
        muestrasRodillaIzq = app.muestras(muestrasRodillaIzq)
        muestrasRodillaIzq = [muestrasRodillaIzq(5000:10000) muestrasRodillaIzq(1:5000)]
        
        muestrasTobilloIzq = app.muestras([3 -1 -9 -15 -19 -20 -12 4 20 22 20 18 17 16 14 13 10 7 4 3 2 3 4])
        muestrasTobilloIzq = [muestrasTobilloIzq(5000:10000) muestrasTobilloIzq(1:5000)] 

        
        curva_tobillo_carrera = [0 2 3 2 -2 -8 -11 -10 -7 3 5 4 3 4 4 3 1 1 0 -3 -3 -2 0];
        [app.tiempo,app.curva_tobillo_carrera_ruido,ejex10k]=funcion_aleatoriedad_rodilla(app,curva_tobillo_carrera);
        
        curva_rodilla_carrera = [176 176 175.5 173 172 174 175 172 165  155 120 95 60 57 63 73 93 117 151 163 172 175 177 177];
        [app.tiempo,app.curva_rodilla_carrera_ruido,ejex10k]=funcion_aleatoriedad_rodilla(app,curva_rodilla_carrera);
       
        curva_angulo_cadera_carrera = [20 17 12 5 -3 -14 -23 -28 -33 -29 -25 -17 -5  11 25 32 41 38 35 34 32 26 19];
        [app.tiempo,app.curva_cadera_carrera_ruido,ejex10k]=funcion_aleatoriedad_rodilla(app,curva_angulo_cadera_carrera);
        
        paso=100;
        
        for i=1:paso:length(ejex10k)-paso-1
           
           cla(app.UIAxes2)
           app.cadenaCinematicaCarreraProtesis([0,0,-muestrasCadera(i)],[0,0,-muestrasRodilla(i)],[0,0,-muestrasTobillo(i)],[0,0,-muestrasCaderaIzq(i)],[0,0,-muestrasRodillaIzq(i)],[0,0,-muestrasTobilloIzq(i)]);
    
            axis(app.UIAxes_2,[ejex10k(1) ejex10k(end) min(app.curva_rodilla_carrera_ruido)-10 max(app.curva_rodilla_carrera_ruido)+10])
            plot(app.UIAxes_2,ejex10k(1:i+paso),app.curva_rodilla_carrera_ruido(1:i+paso),'b')
        
            axis(app.UIAxes,[ejex10k(1) ejex10k(end) min(app.curva_tobillo_carrera_ruido)-10 max(app.curva_tobillo_carrera_ruido)+10])
            plot(app.UIAxes,ejex10k(1:i+paso),app.curva_tobillo_carrera_ruido(1:i+paso),'b')
            
            axis(app.UIAxes_3,[ejex10k(1) ejex10k(end) min(app.curva_cadera_carrera_ruido)-10 max(app.curva_cadera_carrera_ruido)+10])
            plot(app.UIAxes_3,ejex10k(1:i+paso),app.curva_cadera_carrera_ruido(1:i+paso),'b')
            
            app.Slider.Value =(i+paso)/100;
        pause(0.001);
        end
    end
    function [eje_66,curva_filtrada] = funcion_filtrar(~,curva_ruido)

    Fs = 1000;                                                  % Frecuencia de muestreo
    Fn = Fs/2;                                                  % Frecuencia de Nyquist
    Wp = 59/Fn;                                         % Frecuencia de paso normalizada
    Ws = 61/Fn;                                         % Frecuencia de corte normalizada
    Rp =   1;                                                   % Passband Ripple (dB)
    Rs = 150;                                                   % Stopband Ripple (dB)
    [n,Ws] = cheb2ord(Wp,Ws,Rp,Rs);                             % Filter Order
    [z,p,k] = cheby2(n,Rs,Ws);                                  % Filter Design
    [sosbp,gbp] = zp2sos(z,p,k);                                % Convert To Second-Order-Section For Stability

    curva_filtrada = filtfilt(sosbp, gbp, curva_ruido);    % Filter Signal
    eje_66 = linspace(0,1,length(curva_filtrada));
    end
    
    function pintar_filtrar_curva(app)
        

       [eje_filtrado,app.curva_tobillo_carrera_filtrada] =funcion_filtrar(app,app.curva_tobillo_carrera_ruido);
       [eje_filtrado,app.curva_rodilla_carrera_filtrada] =funcion_filtrar(app,app.curva_rodilla_carrera_ruido);
       [eje_filtrado,app.curva_cadera_carrera_filtrada] =funcion_filtrar(app,app.curva_cadera_carrera_ruido);
        cla(app.UIAxes)
        cla(app.UIAxes_3)
        cla(app.UIAxes_2)
        paso=100;
        for i=1:paso:length(eje_filtrado)-paso-1
              
        axis(app.UIAxes_2,[eje_filtrado(1) eje_filtrado(end) min(app.curva_rodilla_carrera_filtrada)-10 max(app.curva_rodilla_carrera_filtrada)+10])
        plot(app.UIAxes_2,eje_filtrado(1:i+paso),app.curva_rodilla_carrera_filtrada(1:i+paso),'b')
        
        axis(app.UIAxes,[eje_filtrado(1) eje_filtrado(end) min(app.curva_tobillo_carrera_filtrada)-10 max(app.curva_tobillo_carrera_filtrada)+10])
        plot(app.UIAxes,eje_filtrado(1:i+paso),app.curva_tobillo_carrera_filtrada(1:i+paso),'b')
        
        axis(app.UIAxes_3,[eje_filtrado(1) eje_filtrado(end) min(app.curva_cadera_carrera_filtrada)-10 max(app.curva_cadera_carrera_filtrada)+10])
        plot(app.UIAxes_3,eje_filtrado(1:i+paso),app.curva_cadera_carrera_filtrada(1:i+paso),'b')
        
        pause(0.001);
        end
        
    end
    
%     function recoger_dato(~,~,handles,~)
%         curva=get(handles.UIAxes,'array');
%        
%         comparacion_resultados_carrera(curva)
%         
%     end
    
    
end  


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.ContinuarButton.Enable = "off";
         app.FiltrarButton.Enable = "off";
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
         app.ContinuarButton.Enable = "off";
         app.ComenzarButton.Enable = "off";
         app.FiltrarButton.Enable = "off";
         pinta(app)
         app.FiltrarButton.Enable = "on";

        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)

app.ContinuarButton.Enable = "off";
app.ComenzarButton.Enable = "off";
app.FiltrarButton.Enable = "off";

app.Informe = Informe_biomecanico_carrera(app,app.tiempo,app.curva_tobillo_carrera_filtrada, app.curva_rodilla_carrera_filtrada, app.curva_cadera_carrera_filtrada);
delete(app)
        end

        % Button pushed function: FiltrarButton
        function FiltrarButtonPushed(app, event)
            app.ContinuarButton.Enable = "off";
            app.ComenzarButton.Enable = "off";
            app.FiltrarButton.Enable = "off";
            pintar_filtrar_curva(app)
         app.ContinuarButton.Enable = "on";

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
             delete(app)
            Pauta_carrera
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Curva del tobillo')
            xlabel(app.UIAxes, '% del movimiento')
            ylabel(app.UIAxes, 'Ángulo del tobillo')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Curva de la rodilla')
            xlabel(app.UIAxes_2, '% del movimiento')
            ylabel(app.UIAxes_2, 'Ángulo de la rodilla')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Curva de la cadera')
            xlabel(app.UIAxes_3, '% del movimiento')
            ylabel(app.UIAxes_3, 'Ángulo de la cadera')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Representación del movimiento')
            ylabel(app.UIAxes2, {''; ''})
            zlabel(app.UIAxes2, 'Z')
            app.CARRERALabel = uilabel(app.UIFigure);
            app.CARRERALabel.Text = 'CARRERA';
            app.Slider = uislider(app.UIFigure);
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.ComenzarButton = uibutton(app.UIFigure, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Screenshot 2022-11-13 at 14.13.57.png';
            app.FiltrarButton = uibutton(app.UIFigure, 'push');
            app.FiltrarButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarButtonPushed, true);
            app.FiltrarButton.Text = 'Filtrar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ejercicio_curva_carrera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end