classdef prueba_ruido < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        muestrasTronco = [79.78 79.15 77.66 74.83 70.39 64.77 58.62 53.20 49.52 48.22 49.40 52.66 57.55 63.44 69.72 75.90 81.33 85.65 88.86 91 92.49] % Description
    end
    
    methods (Access = private)
        
        function  funcion_ruido_seno(app,curva)
         
      ejex = linspace(0,100,length(curva));

      pp = makima(ejex,curva);

      curva10k = ppval(pp,linspace(0,100,10000));

      ejex10k = linspace(0,1,length(curva10k));

        %Valores que vayan de -var a var
        %Quiero 100000 valores random, pero siempre seran entre 0 y 1
        r=rand(1,length(curva10k));
        
        %Valores random de ruido
        %crea valores random de 2 a 9. Pq queda bien 
        var_ruido= 2 + (5+2)*rand(1,1);
        
        %Aplico aleatoreidad, pero a un vector.
        % Cada valor de r va a ser multiplicado por un nº random entre 2 y 9
        % (var_ruido)
        al = var_ruido*r - var_ruido/2;
        
        %Creo función de seno
        
        %Esta es la función del seno genércia
        %f(t) = A*sin(w*t + theta)
        %Asignamos valores a las variables de la función del seno
        
        % Frecuencia: la ponemos en 60 Hz
            freq = 60*pi; 
         
        % Frecuencia angular
            w = 2*pi*freq; 
         
        % Amplitud: le asignamos los valores de aleatoriedad previamente generados
            A = al;  
         
        % Valores de t
            t = linspace(0,pi,10000);  
         
        % Función del seno con sus respectivos valores de frecuencia y amplitud
            seno60 = A .* sin(w*t);
         
        % Sine wave plot
          %plot(t, seno60), title("Sine Wave"),
          %xlabel("Time"), ylabel("Sine wave function")

        %Ahora no van entre 0 y 1, sino entre -0.8 y 0.8
        %Se obtiene una señal con ruido
        curva_ruido_seno = curva10k + seno60;
        plot(app.UIAxes,ejex10k,curva_ruido_seno,'b');
        
end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            funcion_ruido_seno(app,app.muestrasTronco)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba_ruido

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end